classdef Separation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MineralIdentificationTab     matlab.ui.container.Tab
        MinIDExportButton            matlab.ui.control.Button
        NonMag_Table                 matlab.ui.control.Table
        MagneticSusceptibility_Edit  matlab.ui.control.NumericEditField
        MagneticSusceptibilityLabel  matlab.ui.control.Label
        LoadDatabaseButton           matlab.ui.control.Button
        Mag_Table                    matlab.ui.control.Table
        SettingGroup                 matlab.ui.container.ButtonGroup
        SusceptibilityButton         matlab.ui.control.RadioButton
        SlopeButton                  matlab.ui.control.RadioButton
        CurrentButton                matlab.ui.control.RadioButton
        AddPhaseButton               matlab.ui.control.Button
        DatabaseTable                matlab.ui.control.Table
        Slope                        matlab.ui.control.Spinner
        SideAngleSpinnerLabel        matlab.ui.control.Label
        Current                      matlab.ui.control.Spinner
        CurrentASpinnerLabel         matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        WorkflowSuggestionTab        matlab.ui.container.Tab
        WorkflowExport               matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        ReverseButton                matlab.ui.control.RadioButton
        NormalButton                 matlab.ui.control.RadioButton
        ProcedureTextArea            matlab.ui.control.TextArea
        ProcedureTextAreaLabel       matlab.ui.control.Label
        SelectAllCheckBox            matlab.ui.control.CheckBox
        MaxCurrentAEditField         matlab.ui.control.NumericEditField
        MaxCurrentAEditFieldLabel    matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        Step_UITable                 matlab.ui.control.Table
        LoadDatabaseButton_2         matlab.ui.control.Button
        DatabaseTable_2              matlab.ui.control.Table
        MineralCreationTab           matlab.ui.container.Tab
        MinMagCutoffLabel            matlab.ui.control.Label
        MaxMagCutoffLabel            matlab.ui.control.Label
        MinKmInt                     matlab.ui.control.NumericEditField
        MinKmEditFieldLabel_3        matlab.ui.control.Label
        MaxKmInt                     matlab.ui.control.NumericEditField
        MaxKmEditFieldLabel_3        matlab.ui.control.Label
        MinKMCutOff                  matlab.ui.control.NumericEditField
        MaxKMCutOff                  matlab.ui.control.NumericEditField
        XDropDown                    matlab.ui.control.DropDown
        YDropDown                    matlab.ui.control.DropDown
        RemoveDatapointButton        matlab.ui.control.Button
        AddDatapointButton           matlab.ui.control.Button
        ExportMineralButton          matlab.ui.control.Button
        ConditionsTable              matlab.ui.control.Table
        LoadMineralButton            matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        DatabaseModificationTab      matlab.ui.container.Tab
        TabGroup2                    matlab.ui.container.TabGroup
        AddDeletePhaseTab            matlab.ui.container.Tab
        ExportAddPhase               matlab.ui.control.Button
        Name                         matlab.ui.control.EditField
        PhaseNameLabel               matlab.ui.control.Label
        UpdateTables                 matlab.ui.control.Button
        Delete                       matlab.ui.control.Button
        AddPhase                     matlab.ui.control.Button
        AddDatabaseTable             matlab.ui.control.Table
        InputType                    matlab.ui.container.ButtonGroup
        MaxKm2                       matlab.ui.control.NumericEditField
        MaxKmEditFieldLabel_2        matlab.ui.control.Label
        Slope2                       matlab.ui.control.NumericEditField
        Slope2EditFieldLabel         matlab.ui.control.Label
        Current2                     matlab.ui.control.NumericEditField
        Current2EditFieldLabel       matlab.ui.control.Label
        MinKm2                       matlab.ui.control.NumericEditField
        MinKmEditFieldLabel_2        matlab.ui.control.Label
        Slope1                       matlab.ui.control.NumericEditField
        Slope1EditFieldLabel         matlab.ui.control.Label
        Current1                     matlab.ui.control.NumericEditField
        Current1EditFieldLabel       matlab.ui.control.Label
        CalculateKm                  matlab.ui.control.Button
        MaxKm                        matlab.ui.control.NumericEditField
        MaxKmEditField_3Label        matlab.ui.control.Label
        MinKm                        matlab.ui.control.NumericEditField
        MinKmEditField_3Label        matlab.ui.control.Label
        FrantzSettingsButton         matlab.ui.control.RadioButton
        KmButton                     matlab.ui.control.RadioButton
        AddCompositePhaseTab         matlab.ui.container.Tab
        CompositeExport              matlab.ui.control.Button
        CompositeName                matlab.ui.control.EditField
        PhaseNameEditFieldLabel      matlab.ui.control.Label
        CreateComposite              matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        AddCompositeDatabase         matlab.ui.control.Table
        AddButton                    matlab.ui.control.Button
        UpdateTables2                matlab.ui.control.Button
        Delete_2                     matlab.ui.control.Button
        DatabaseComposite            matlab.ui.control.Table
        SelectPhaseDropDown          matlab.ui.control.DropDown
        SelectPhaseDropDownLabel     matlab.ui.control.Label
        MinIDExportHelp              matlab.ui.container.ContextMenu
        HelpMenu                     matlab.ui.container.Menu
        MinIDLoadHelp                matlab.ui.container.ContextMenu
        MinIDLoad                    matlab.ui.container.Menu
        MinIDAdd                     matlab.ui.container.ContextMenu
        MinIDAdd_Help                matlab.ui.container.Menu
        MinIDData                    matlab.ui.container.ContextMenu
        MinIDData_Help               matlab.ui.container.Menu
        MinIDKm                      matlab.ui.container.ContextMenu
        MinIDKm_Help                 matlab.ui.container.Menu
        MinIDMag                     matlab.ui.container.ContextMenu
        MinIDMag_Help                matlab.ui.container.Menu
        MinIDNonMag                  matlab.ui.container.ContextMenu
        MinIDNonMag_Help             matlab.ui.container.Menu
        MinIDCurrent                 matlab.ui.container.ContextMenu
        MinIDCurrent_Help            matlab.ui.container.Menu
        MinIDSlope                   matlab.ui.container.ContextMenu
        MinIDSlope_Help              matlab.ui.container.Menu
        MinIDSelection               matlab.ui.container.ContextMenu
        MinIDSelection_Help          matlab.ui.container.Menu
        MinID                        matlab.ui.container.ContextMenu
        MinIDHelp                    matlab.ui.container.Menu
        Work                         matlab.ui.container.ContextMenu
        Work_Help                    matlab.ui.container.Menu
        WorkLoad                     matlab.ui.container.ContextMenu
        WorkLoad_Help                matlab.ui.container.Menu
        WorkCalc                     matlab.ui.container.ContextMenu
        WorkCalc_Help                matlab.ui.container.Menu
        WorkMaxI                     matlab.ui.container.ContextMenu
        WorkMaxI_Help                matlab.ui.container.Menu
        WorkMode                     matlab.ui.container.ContextMenu
        WorkMode_Help                matlab.ui.container.Menu
        WorkExport                   matlab.ui.container.ContextMenu
        WorkExport_Help              matlab.ui.container.Menu
        WorkSelect                   matlab.ui.container.ContextMenu
        WorkSelect_Help              matlab.ui.container.Menu
        Create                       matlab.ui.container.ContextMenu
        Create_Help                  matlab.ui.container.Menu
        CreateLoad                   matlab.ui.container.ContextMenu
        CreateLoad_Help              matlab.ui.container.Menu
        CreateExport                 matlab.ui.container.ContextMenu
        CreateExport_Help            matlab.ui.container.Menu
        CreateAdd                    matlab.ui.container.ContextMenu
        CreateAdd_Help               matlab.ui.container.Menu
        CreateMaxCut                 matlab.ui.container.ContextMenu
        CreateMaxCut_Help            matlab.ui.container.Menu
        CreateMinCut                 matlab.ui.container.ContextMenu
        CreateMinCut_Help            matlab.ui.container.Menu
        CreateTable                  matlab.ui.container.ContextMenu
        CreateTable_Help             matlab.ui.container.Menu
        CreateMinK                   matlab.ui.container.ContextMenu
        CreateMinK_Help              matlab.ui.container.Menu
        CreateMaxK                   matlab.ui.container.ContextMenu
        CreateMaxK_Help              matlab.ui.container.Menu
        Mod                          matlab.ui.container.ContextMenu
        Mod_Help                     matlab.ui.container.Menu
        ModAdd                       matlab.ui.container.ContextMenu
        ModAdd_Help                  matlab.ui.container.Menu
        ModAddType                   matlab.ui.container.ContextMenu
        ModAddType_Help              matlab.ui.container.Menu
        ModAddMin                    matlab.ui.container.ContextMenu
        ModAddMin_Help               matlab.ui.container.Menu
        ModAddMax                    matlab.ui.container.ContextMenu
        ModAddMax_Help               matlab.ui.container.Menu
        ModC1                        matlab.ui.container.ContextMenu
        ModC1_Help                   matlab.ui.container.Menu
        ModS1                        matlab.ui.container.ContextMenu
        ModS1_Help                   matlab.ui.container.Menu
        ModAddMin2                   matlab.ui.container.ContextMenu
        ModAddMin2_Help              matlab.ui.container.Menu
        ModC2                        matlab.ui.container.ContextMenu
        ModC2_Help                   matlab.ui.container.Menu
        ModS2                        matlab.ui.container.ContextMenu
        ModS2_Help                   matlab.ui.container.Menu
        ModAddMax2                   matlab.ui.container.ContextMenu
        ModAddMax2_Help              matlab.ui.container.Menu
        ModCalc                      matlab.ui.container.ContextMenu
        ModCalc_Help                 matlab.ui.container.Menu
        ModPhase                     matlab.ui.container.ContextMenu
        ModPhase_Help                matlab.ui.container.Menu
        ModExport                    matlab.ui.container.ContextMenu
        ModExport_Help               matlab.ui.container.Menu
        ModUpdate                    matlab.ui.container.ContextMenu
        ModUpdate_Help               matlab.ui.container.Menu
        CompSelect                   matlab.ui.container.ContextMenu
        CompSelect_Help              matlab.ui.container.Menu
        Comp                         matlab.ui.container.ContextMenu
        Comp_Help                    matlab.ui.container.Menu
        CompAdd                      matlab.ui.container.ContextMenu
        CompAdd_Help                 matlab.ui.container.Menu
        CompTable                    matlab.ui.container.ContextMenu
        CompTable_Help               matlab.ui.container.Menu
        CompCombine                  matlab.ui.container.ContextMenu
        CompCombine_Help             matlab.ui.container.Menu
        CompUpdate                   matlab.ui.container.ContextMenu
        CompUpdate_Help              matlab.ui.container.Menu
        CompExport                   matlab.ui.container.ContextMenu
        CompExport_Help              matlab.ui.container.Menu
        CreateRemove                 matlab.ui.container.ContextMenu
        CreateRemove_Help            matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Original_Database % Description
        UIConitionsTableIndex
        AddPhase_handle
        OperatingDatabase
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadDatabaseButton
        function LoadDatabase(app, event)
            [filename, path]   = uigetfile('*.xlsx');
            cd(path)
            Database           = readtable(filename);          % Read the spreadsheet database
            
            app.Original_Database = Database;                  % Loaded Database
            Minerals           = Database(:,1);                % Minerals
            MinSus             = Database(:,2);                % Maximnum Magnetic Susceptibility
            MaxSus             = Database(:,3);                % Minimum Magnetic Susceptibility
            NumberofMinerals   = size(Database);               % Number of Minerals in the Database
            Select             = false(NumberofMinerals(1),1); % Add Selected Minerals Column to GUI Table
            
            I                  = app.Current.Value;            % User inputed Current
            SlopeAngle         = app.Slope.Value;              % User inputed Slope
            CurrentOn          = app.CurrentButton.Value;      % Is Current Mode Selected?
            SlopeOn            = app.SlopeButton.Value;        % Is Slope Mode Selected?
            
            MinSus             = table2array(MinSus);          % Minimum Magnetic Susceptibility Column
            MaxSus             = table2array(MaxSus);          % Maximum Magnetic Susceptibility Column
            Minerals           = table2cell(Minerals);         % Mineral Names
            
            app.SelectPhaseDropDown.Items = Minerals;          % Set dropdown menu in Database Modification Tab
             
            AddDatabase                = table(Minerals,real(MinSus),real(MaxSus),Select); % Create datatable 
            app.AddDatabaseTable.Data  = AddDatabase;  % Update the datatables in Database Modification Tab
            app.DatabaseComposite.Data = AddDatabase;  % Update the datatables in Database Modification Ta
            
            if CurrentOn
                for i = 1:NumberofMinerals
                    MaxValue(i) = 4.7884*(sind(SlopeAngle)/1e6/MinSus(i))^0.5142; % Convert to Min Current
                    MinValue(i) = 4.7884*(sind(SlopeAngle)/1e6/MaxSus(i))^0.5142; % Convert to Max Current
                end
                Database        = table(Minerals,real(MinValue'),real(MaxValue'),Select);  % Create datatable
            end
            
            if SlopeOn
                for i = 1:NumberofMinerals
                    MinValue(i) = asind(MinSus(i)*1e6*(I/4.7884)^(1/0.5142)); % Convert to Min Slope
                    MaxValue(i) = asind(MaxSus(i)*1e6*(I/4.7884)^(1/0.5142)); % Convert to Max Slope
                end
                Database        = table(Minerals,real(MaxValue'),real(MinValue'),Select);
            end           
            app.DatabaseTable.Data = Database;   % Update table in Mineral Identification Tab
            app.DatabaseTable_2.Data = Database; % Update table in Workflow Suggestion Tab  

        end

        % Value changed function: Current
        function CurrentChange(app, event)
            SettingChange(app)            
        end

        % Value changed function: Slope
        function SlopeChange(app, event)
            SettingChange(app)
        end

        % Selection changed function: SettingGroup
        function SettingChange(app, event)
            Selected   = app.SettingGroup.SelectedObject.Text; % New Datatable mode
            I          = app.Current.Value;                    % Current
            SlopeAngle = app.Slope.Value;                      % Slope
            Data       = app.DatabaseTable.Data;               % Datatable
            DataNames  = app.DatabaseTable.ColumnName;         % Data Column Names
            Database   = app.Original_Database;                % Loaded/Updated Database
            
            if ~isempty(Database)
            MinSus             = Database(:,2);                % Maximnum Magnetic Susceptibility
            MaxSus             = Database(:,3);                % Minimum Magnetic Susceptibility
            MinSus             = table2array(MinSus);          % Minimum Magnetic Susceptibility Column
            MaxSus             = table2array(MaxSus);          % Maximum Magnetic Susceptibility Column
            
            if Selected(1) == 'C'                                                      % If current mode is now enabled
                for i=1:length(MinSus)
                    MaxCurrent(i) = 4.7884*(sind(SlopeAngle)/1e6/MinSus(i))^0.5142;    % Calculate min current
                    MinCurrent(i) = 4.7884*(sind(SlopeAngle)/1e6/MaxSus(i))^0.5142;    % Calculate max current
                end
                Data(:,3)                     = table(real(reshape(MaxCurrent,[],1))); % Update Datatable
                Data(:,2)                     = table(real(reshape(MinCurrent,[],1))); % Update Datatable
                app.DatabaseTable.Data        = Data;                                  % Update table in Mineral Identification Tab
                app.DatabaseTable_2.Data      = Data;                                  % Update table in Workflow Suggestion Tab  
                DataNames(3)                  = {'Max Current'};                       % Column Name
                DataNames(2)                  = {'Min Current'};                       % Column Name
                app.DatabaseTable.ColumnName  = DataNames;                             % Update table in Mineral Identification Tab
                app.DatabaseTable_2.ColumnName = DataNames;                            % Update table in Workflow Suggestion Tab  
            end
            
            if Selected(2) == 'l'                                             % If slope mode is now enabled
                for i=1:length(MinSus)
                    MinSlope(i) = asind(MinSus(i)*1e6*(I/4.7884)^(1/0.5142)); % Calculate min slope
                    MaxSlope(i) = asind(MaxSus(i)*1e6*(I/4.7884)^(1/0.5142)); % Calculate max slope
                end
                Data(:,3)                      = table(real(reshape(MinSlope,[],1)));  % Update Datatable
                Data(:,2)                      = table(real(reshape(MaxSlope,[],1)));  % Update Datatable
                app.DatabaseTable.Data         = Data;                                 % Update table in Mineral Identification Tab
                app.DatabaseTable_2.Data       = Data;                                 % Update table in Workflow Suggestion Tab 
                DataNames(3)                   = {'Min Slope'};                        % Column Name
                DataNames(2)                   = {'Max Slope'};                        % Column Name
                app.DatabaseTable.ColumnName   = DataNames;                            % Update table in Mineral Identification T
                app.DatabaseTable_2.ColumnName = DataNames;                            % Update table in Workflow Suggestion Tab  
            end
            
            if Selected(3) == 's'
                Data(:,2)                      = table(real(reshape(MinSus,[],1))); % Update Datatable
                Data(:,3)                      = table(real(reshape(MaxSus,[],1)));
                app.DatabaseTable.Data         = Data;
                app.DatabaseTable_2.Data       = Data;
                DataNames(2)                   = {'Min Km'};
                DataNames(3)                   = {'Max Km'};
                app.DatabaseTable.ColumnName   = DataNames;
                app.DatabaseTable_2.ColumnName = DataNames;
            end
            end
            DatabaseTableCellEdit(app)
        end

        % Cell edit callback: DatabaseTable
        function DatabaseTableCellEdit(app, event)
            cla(app.UIAxes,'reset')
            Database    = app.Original_Database;
            I           = app.Current.Value;                          % Frantz Current
            SlopeAngle  = app.Slope.Value;                            % Slope of Frantz
            MagSusc     = sind(SlopeAngle)/1e6/(I/4.7884)^(1/0.5142); % Magnetic Susceptibility for the current settings
            app.MagneticSusceptibility_Edit.Value = MagSusc;          % Set Value in Mineral Identification Tab
            
            if ~isempty(Database)
                Names    = Database(:,1);                     % Mineral Names
                MaxSus   = Database(:,2);                     % Maximnum Magnetic Susceptibility
                MinSus   = Database(:,3);                     % Min Magnetic Susceptibility
                Names    = table2array(Names);                % Mineral Name Column
                MinSus   = table2array(MinSus);               % Minimum Magnetic Susceptibility Column
                MaxSus   = table2array(MaxSus);               % Maximum Magnetic Susceptibility Column
                
                app.SelectPhaseDropDown.Items = Names;        % Update dropdown menu in Database Modification Tab
                
                Data         = app.DatabaseTable.Data;
                SelectedData = table2array(Data(:,4));
                SelectOn     = find(SelectedData == 1);
                MinSus       = MinSus(SelectOn);
                MaxSus       = MaxSus(SelectOn);
                Names        = Names(SelectOn);
                
                Check1 = MagSusc <= MinSus; % Check if all are collected at Frantz setting
                Check2 = MagSusc >= MaxSus; % Check if none are collected at Frantz setting
                Check3 = Check1 + Check2;   % Check if there is a mix
                
                Mag          = find(Check1==1);
                MagNames     = Names(Mag);
                MagValue     = MaxSus(Mag);
                NonMag       = find(Check2==1);
                NonMagValue  = MinSus(NonMag);
                NonMagNames  = Names(NonMag);
                MixMag       = find(Check3==0);
                MixMagNames  = Names(MixMag);
                MixMagValue1 = MaxSus(MixMag);
                MixMagValue2 = MinSus(MixMag);
                
                MagValueList          = [MagValue; MixMagValue1];
                [MagSort, MagOrginal] = sort(MagValueList,'descend');
                OriginalMagNames      = [MagNames; MixMagNames];
                
                MagNamesSorted = OriginalMagNames;
                
                for i = 1:length(MagSort)
                    MagNamesSorted(i) = OriginalMagNames(MagOrginal(i));
                end
                
                NonMagValueList             = [NonMagValue; MixMagValue2];
                [NonMagSort, NonMagOrginal] = sort(NonMagValueList);
                OriginalNonMagNames         = [NonMagNames; MixMagNames];
                
                NonMagNamesSorted = OriginalNonMagNames;
                for i = 1:length(NonMagSort)
                    NonMagNamesSorted(i) = OriginalNonMagNames(NonMagOrginal(i));
                end
                
                app.Mag_Table.Data    = MagNamesSorted;
                app.NonMag_Table.Data = NonMagNamesSorted;
                
                TableData  = app.DatabaseTable.Data;
                TableDataA = table2array(TableData(:,[2,3]));
                TableDataA = TableDataA(SelectOn,:);
                MineralNum = 1:length(SelectOn);
                
                Selected   = app.SettingGroup.SelectedObject.Text;
                
                if Selected(2) == 'l'                                               % If slope mode is now enabled
                    cla(app.UIAxes)
                    app.UIAxes.XLim          = [0,20];
                    app.UIAxes.YLim          = [0, length(MineralNum)+1];
                    app.UIAxes.YTick         = MineralNum;
                    app.UIAxes.YTickLabel    = Names;
                    app.UIAxes.XLabel.String = 'Slope (Degrees)';
                    
                    for i = MineralNum
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,[TableDataA(i,1), 90], [MineralNum(i), MineralNum(i)],'LineWidth',10, "Color",'b')
                        plot(app.UIAxes,[TableDataA(i,1), TableDataA(i,2)], [MineralNum(i), MineralNum(i)],'LineWidth',10,"Color",[0.9290 0.6940 0.1250])
                        plot(app.UIAxes,[0, TableDataA(i,2)], [MineralNum(i), MineralNum(i)],'LineWidth',10,"Color",'r')
                    end
                    legend(app.UIAxes,'Non-Magnetic', 'Potentially Magnetic', 'Magnetic' )
                    hold(app.UIAxes,'off')
                    
                else
                    if SelectOn >= 1
                        cla(app.UIAxes)
                        app.UIAxes.XLim          = [0,2.5];
                        app.UIAxes.YLim          = [0, length(MineralNum)+1];
                        app.UIAxes.YTick         = MineralNum;
                        app.UIAxes.YTickLabel    = Names;
                        app.UIAxes.XLabel.String = 'Current (A)';
                        
                        if Selected(3) == 's'                                               % If current mode is now enabled
                            MinSus               = TableDataA(:,1);
                            MaxSus               = TableDataA(:,2);
                            
                            for i=1:length(MinSus)
                                TableDataA(i,2) = 4.7884*(sind(SlopeAngle)/1e6/MinSus(i))^0.5142; % Calculate min current
                                TableDataA(i,1) = 4.7884*(sind(SlopeAngle)/1e6/MaxSus(i))^0.5142; % Calculate max current
                            end
                            
                        end 
                        
                        for i = MineralNum
                            hold(app.UIAxes,'on')
                            plot(app.UIAxes,[TableDataA(i,2), 2.5], [MineralNum(i), MineralNum(i)],'LineWidth',10, "Color",'r')
                            plot(app.UIAxes,[TableDataA(i,1), TableDataA(i,2)], [MineralNum(i), MineralNum(i)],'LineWidth',10,"Color",[0.9290 0.6940 0.1250])
                            plot(app.UIAxes,[0, TableDataA(i,1)], [MineralNum(i), MineralNum(i)],'LineWidth',10,"Color",'b')
                        end
                        
                        legend(app.UIAxes,'Magnetic', 'Potentially Magnetic', 'Non-Magnetic' )
                        hold(app.UIAxes,'off')
                        
                    end
                end
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            tabledata      = app.Step_UITable.Data;
            Database       = app.Original_Database;
            ColumnHeaders  = app.Step_UITable.ColumnName;
            ColumnHeaders2 = app.DatabaseTable_2.ColumnName;
            NormalOn       = app.NormalButton.Value;
            Names          = Database(:,1);
            MinSus         = Database(:,2);                     % Maximnum Magnetic Susceptibility
            MaxSus         = Database(:,3);                     % Min Magnetic Susceptibility
            Names          = table2array(Names);
            MinSus         = table2array(MinSus);               % Minimum Magnetic Susceptibility Column
            MaxSus         = table2array(MaxSus);               % Maximum Magnetic Susceptibility Column
            MaxI           = app.MaxCurrentAEditField.Value;
            
            Data         = app.DatabaseTable_2.Data;
            SelectedData = table2array(Data(:,4));
            SelectOn     = find(SelectedData == 1);
            
            if ~isempty(SelectOn)
                MinSusOn           = MinSus(SelectOn);
                MaxSusOn           = MaxSus(SelectOn);
                Names              = Names(SelectOn);
                data_Sus           = [MinSusOn', MaxSusOn'];
                dataSus_reshaped   = reshape(data_Sus,[],1);
                [sortedSus_data,~] = sort(unique(dataSus_reshaped));
                SlopeAngle         = asind(sortedSus_data(2)*1e6*(MaxI/4.7884)^(1/0.5142));
                SlopeAngle         = round(SlopeAngle);
                
                for i=1:length(MinSusOn)
                    MaxCurrent(i) = 4.7884*(sind(SlopeAngle)/1e6/MinSusOn(i))^0.5142; % Calculate min current
                    MinCurrent(i) = 4.7884*(sind(SlopeAngle)/1e6/MaxSusOn(i))^0.5142; % Calculate max current
                end
                
                data = [MinCurrent', MaxCurrent'];
                
                if NormalOn == 1
                    data_reshaped      = reshape(data,[],1);
                    [sorted_data,~]    = sort(data_reshaped,'descend');
                    NumSelected        = length(data_reshaped)/2;
                    
                    unique_sorted_data = sort(unique(sorted_data),'descend');
                    NumSteps           = length(unique_sorted_data)-2;
                    
                    for i = 1:NumSteps
                        Step(i)            = i;                                % Step Number
                        CurrentValue(i)    = unique_sorted_data(i+1);          % Current to Check (i+1) for Step i
                        DataCheck          = data;                             % Create a new array to check non-mag vs mag behavior
                        MagCheck           = CurrentValue(i) < DataCheck(:,1); % Step I is less than Min I - Always non-mag
                        DataCheck(:,end+1) = MagCheck;                         % If 0 - Sometimes Magnetic, If 1 - Always non-mag
                        NonMagMin          = 0;                                % Create empty non magnetic mineral list
                        MagMin             = 0;                                % Create empty magnetic mineral list
                        
                        for k = 1:NumSelected                                  % For each mineral
                            if DataCheck(k,3) == 0                             % If the mineral is sometimes magnetic
                                if DataCheck(k,2) > CurrentValue(i)            % If max current > current for step i --> non-mag
                                    NonMagMin(end+1) = k;                      % Idenfify the non mag netic mineral
                                end
                                if DataCheck(k,1) < CurrentValue(i)            % If min current > current for step i --> mag
                                    MagMin(end+1) = k;                         % Idenfify the magnetic mineral
                                end
                            end
                        end
                        
                        NonMagMin(1) = [];                          % Delete the empty data at beggining of list
                        MagMin(1)    = [];                          % Delete the empty data at beggining of list
                        NonMagNames  = Names(NonMagMin);            % Get Non magnetic mineral names
                        MagNames     = Names(MagMin);               % Get magnetic mineral names
                        MagNames     = strcat(MagNames,',');
                        MagNames     = strjoin(MagNames);
                        NonMagNames  = strcat(NonMagNames,',');
                        NonMagNames  = strjoin(NonMagNames);
                        
                        if  ~isempty(NonMagNames)
                            NonMagNames(end) = [];
                        end
                        
                        if i == 1
                            tabledata      = [Step(i), CurrentValue(i), SlopeAngle, {MagNames}, {NonMagNames}];
                        else
                            tabledata(i,:) = [Step(i), CurrentValue(i), SlopeAngle, {MagNames}, {NonMagNames}];
                        end
                    end
                    
                    empty          = cellfun(@isempty,tabledata);
                    [r,~]          = find(empty==1);
                    tabledata(r,:) = [];
                    
                    for i = 1:size(tabledata,1)
                        tabledata(i,1) = {i};
                    end
                    
                    app.Step_UITable.Data = tabledata;
                    
                    for i=1:length(MinSus)
                        MaxCurrent(i) = 4.7884*(sind(SlopeAngle)/1e6/MinSus(i))^0.5142; % Calculate min current
                        MinCurrent(i) = 4.7884*(sind(SlopeAngle)/1e6/MaxSus(i))^0.5142; % Calculate max current
                    end
                    
                    Data(:,3)                = table(real(reshape(MaxCurrent,[],1))); % Update Data Table
                    Data(:,2)                = table(real(reshape(MinCurrent,[],1)));
                    app.DatabaseTable_2.Data = Data;
                end
                
                ColumnHeaders2(3)              = {'Max Current'};                       % Column Name
                ColumnHeaders2(2)              = {'Min Current'};                       % Column Name
                app.DatabaseTable_2.ColumnName = ColumnHeaders2;
                
                ColumnHeaders(4)            = {'Mag Minerals (Process)'};
                ColumnHeaders(5)            = {'Non-Mag Minerals (Collect)'};
                app.Step_UITable.ColumnName = ColumnHeaders;
            end
            if NormalOn == 0
                data_reshaped      = reshape(data,[],1);
                [sorted_data,~]    = sort(data_reshaped,'ascend');
                NumSelected        = length(data_reshaped)/2;
                
                unique_sorted_data = sort(unique(sorted_data),'ascend');
                NumSteps           = length(unique_sorted_data)-2;
                
                for i = 1:NumSteps
                    Step(i)            = i;                                % Step Number
                    CurrentValue(i)    = unique_sorted_data(i+1);          % Current to Check (i+1) for Step i
                    DataCheck          = data;                             % Create a new array to check non-mag vs mag behavior
                    MagCheck           = CurrentValue(i) > DataCheck(:,2); % Step I is less than Min I - Always mag
                    DataCheck(:,end+1) = MagCheck;                         % If 0 - Sometimes Non-Magnetic, If 1 - Always mag
                    NonMagMin          = 0;                                % Create empty non magnetic mineral list
                    MagMin             = 0;                                % Create empty magnetic mineral list
                    
                    for k = 1:NumSelected                        % For each mineral
                        if DataCheck(k,3) == 0                   % If the mineral is sometimes non-magnetic
                            if DataCheck(k,2) > CurrentValue(i)  % If max current > current for step i --> non-mag
                                NonMagMin(end+1) = k;
                            end
                            
                            if DataCheck(k,1) < CurrentValue(i) % If min current > current for step i --> mag
                                MagMin(end+1) = k;
                            end
                        end
                    end
                    
                    NonMagMin(1) = [];                          % Delete the empty data at beggining of list
                    MagMin(1)    = [];
                    NonMagNames  = Names(NonMagMin);
                    MagNames     = Names(MagMin);
                    MagNames     = strcat(MagNames,',');
                    MagNames     = strjoin(MagNames);
                    NonMagNames  = strcat(NonMagNames,',');
                    NonMagNames  = strjoin(NonMagNames);
                    
                    if  ~isempty(MagNames)
                        NonMagNames(end) = [];
                    end
                    
                    if i == 1
                        tabledata = [Step(i), CurrentValue(i), SlopeAngle, {MagNames}, {NonMagNames}];
                    else
                        tabledata(i,:) = [Step(i), CurrentValue(i), SlopeAngle, {MagNames}, {NonMagNames}];
                    end
                    
                end
                
                empty          = cellfun(@isempty,tabledata);
                [r,~]          = find(empty==1);
                tabledata(r,:) = [];
                
                for i = 1:size(tabledata,1)
                    tabledata(i,1) = {i};
                end
                
                app.Step_UITable.Data = tabledata;
                
                for i=1:length(MinSus)
                    MaxCurrent(i) = 4.7884*(sind(SlopeAngle)/1e6/MinSus(i))^0.5142; % Calculate min current
                    MinCurrent(i) = 4.7884*(sind(SlopeAngle)/1e6/MaxSus(i))^0.5142; % Calculate max current
                end
                Data(:,3)                = table(real(reshape(MaxCurrent,[],1))); % Update Data Table
                Data(:,2)                = table(real(reshape(MinCurrent,[],1)));
                app.DatabaseTable_2.Data = Data;
                                
                ColumnHeaders2(3)              = {'Max Current'};                       % Column Name
                ColumnHeaders2(2)              = {'Min Current'};                       % Column Name
                app.DatabaseTable_2.ColumnName = ColumnHeaders2;
                
                ColumnHeaders(4)               = {'Mag Minerals (Collect)'};
                ColumnHeaders(5)               = {'Non-Mag Minerals (Process)'};
                app.Step_UITable.ColumnName    = ColumnHeaders;
            end
        end

        % Button pushed function: LoadDatabaseButton_2
        function LoadDatabaseButton_2Pushed(app, event)
            LoadDatabase(app)
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            value    = app.SelectAllCheckBox.Value;
            Data     = app.DatabaseTable_2.Data;
            SelectOn = Data(:,4);
            
            if value == 1
                SelectOn(:,1) = {true};
            else
                SelectOn(:,1) = {false};
            end
            
            app.DatabaseTable_2.Data(:,4) = SelectOn;
        end

        % Button pushed function: LoadMineralButton
        function LoadMineralButtonPushed(app, event)
            [filename, path]           = uigetfile('*.xlsx');
            cd(path)
            MinDatabase                = readtable(filename);            % Read the spreadsheet database
            app.OperatingDatabase.Data = MinDatabase;
            DBCurrent                  = table2array(MinDatabase(:,1));              % Current for datapoint
            DBSlope                    = table2array(MinDatabase(:,2));              % Slope for datapoint
            MagPercent                 = table2array(MinDatabase(:,3));                % Percent of grains magnetic
            NonMagPercent              = table2array(MinDatabase(:,4));               % Percent of grains non-magnetic
            Km                         = sind(DBSlope)/1e6./((DBCurrent/4.7884).^(1/0.5142)); % Magnetic Susceptibility for the current settings
            NumDatapts                 = size(MinDatabase);                % Number of data points in the Database
            Select                     = true(NumDatapts(1),1);         % Add Selected Data Column to GUI Table
            
            MinDatabase                = table(DBCurrent,DBSlope,MagPercent,NonMagPercent,Km,Select);
            app.ConditionsTable.Data   = MinDatabase;
            
            XValue                     = app.XDropDown.Value;
            
            switch(XValue)
                case 'Current'
                    x = 1;
                case 'Slope'
                    x = 2;
                case 'Mag(%)'
                    x = 3;
                case 'NonMag(%)'
                    x = 4;
                case 'Km'
                    x = 5;
            end
            
            YValue = app.YDropDown.Value;
            
            switch(YValue)
                case 'Current'
                    y = 1;
                case 'Slope'
                    y = 2;
                case 'Mag(%)'
                    y = 3;
                case 'NonMag(%)'
                    y = 4;
                case 'Km'
                    y = 5;
            end
            
            Selected   = find(Select == 1);
            DataValues = [DBCurrent,DBSlope,MagPercent,NonMagPercent,Km];
            X          = DataValues(Selected,x);
            Y          = DataValues(Selected,y);
            
            cla(app.UIAxes2)
            plot(app.UIAxes2, X,Y,'-','LineWidth',2)
            hold (app.UIAxes2,"on")
            plot(app.UIAxes2, X,Y,'o','LineWidth',2)
            
            MaxCutOff           = app.MaxKMCutOff.Value;            
            MaxCompare          = MagPercent-MaxCutOff;
            MaxCompare2         = MaxCompare;
            MaxCompare3         = -MaxCompare;
            NegVal              = find(MaxCompare2<0);
            MaxCompare2(NegVal) = NaN;
            [~,MaxIndex1]       = min(MaxCompare2);
            
            NegVal              = find(MaxCompare3<0);
            MaxCompare3(NegVal) = NaN;
            [~,MaxIndex2]       = min(MaxCompare3);

            mdl     = fitlm(MagPercent(MaxIndex1:MaxIndex2),Km(MaxIndex1:MaxIndex2));
            MaxCoef = mdl.Coefficients;
            m_Max   = table2array(MaxCoef(2,1));
            int_Max = table2array(MaxCoef(1,1));
            KMaxInt = m_Max*MaxCutOff+int_Max;
            
            MinCutOff           = app.MinKMCutOff.Value;
            MinCompare          = MagPercent-MinCutOff;
            MinCompare2         = MinCompare;
            MinCompare3         = -MinCompare;
            NegVal              = find(MinCompare2<0);
            MinCompare2(NegVal) = NaN;
            [~,MinIndex1]       = min(MinCompare2);
            
            NegVal              = find(MinCompare3<0);
            MinCompare3(NegVal) = NaN;
            [~,MinIndex2]       = min(MinCompare3);
            
            mdl                 = fitlm(MagPercent(MinIndex1:MinIndex2),Km(MinIndex1:MinIndex2));
            MinCoef             = mdl.Coefficients;
            m_Min               = table2array(MinCoef(2,1));
            int_Min             = table2array(MinCoef(1,1));
            KMinInt             = m_Min*MinCutOff+int_Min;
            
            app.MaxKmInt.Value  = KMaxInt;
            app.MinKmInt.Value  = KMinInt;
             
        end

        % Button pushed function: AddDatapointButton
        function AddDatapointButtonPushed(app, event)
            Data                     = app.ConditionsTable.Data;
            NewData                  = Data(1,:);
            NewData                  = [NewData;Data];
            DataValues               = table2array(NewData);
            Selected                 = find(DataValues(:,6) == 1);
            app.ConditionsTable.Data = NewData;
            YValue                   = app.YDropDown.Value;
            XValue                   = app.XDropDown.Value;
            
            switch(XValue)
                case 'Current'
                    x = 1;
                case 'Slope'
                    x = 2;
                case 'Mag(%)'
                    x = 3;
                case 'NonMag(%)'
                    x = 4;
                case 'Km'
                    x = 5;
            end
            
            switch(YValue)
                case 'Current'
                    y = 1;
                case 'Slope'
                    y = 2;
                case 'Mag(%)'
                    y = 3;
                case 'NonMag(%)'
                    y = 4;
                case 'Km'
                    y = 5;
            end
            
            X = DataValues(Selected,x);
            Y = DataValues(Selected,y);
            
            cla(app.UIAxes2)
            plot(app.UIAxes2, X,Y,'-','LineWidth',2)
            hold (app.UIAxes2,"on")
            plot(app.UIAxes2, X,Y,'o','LineWidth',2)
        end

        % Value changed function: YDropDown
        function YDropDownValueChanged(app, event)
            YValue = app.YDropDown.Value;
            XValue = app.XDropDown.Value;
            Data   = app.ConditionsTable.Data;
            
            if ~isempty(Data)
                DataValues = table2array(Data);
                Selected   = find(DataValues(:,6) == 1);
                
                switch(XValue)
                    case 'Current'
                        x = 1;
                    case 'Slope'
                        x = 2;
                    case 'Mag(%)'
                        x = 3;
                    case 'NonMag(%)'
                        x = 4;
                    case 'Km'
                        x = 5;
                end
                
                switch(YValue)
                    case 'Current'
                        y = 1;
                    case 'Slope'
                        y = 2;
                    case 'Mag(%)'
                        y = 3;
                    case 'NonMag(%)'
                        y = 4;
                    case 'Km'
                        y = 5;
                end
                
                DataValues = sortrows(DataValues,5);
                
                X  = DataValues(Selected,x);
                Y  = DataValues(Selected,y);
                
                Y2 = DataValues(Selected,3);
                X2 = DataValues(Selected,5);
                
                p  = fit(X2,Y2,'poly1');
                p2 = fit(X2,Y2,'poly2');
                p3 = fit (Y2,X2,'exp2');
                
                cla(app.UIAxes2)
                plot(app.UIAxes2, X,Y,'-','LineWidth',2)
                hold (app.UIAxes2,"on")
                plot(app.UIAxes2, X,Y,'o','LineWidth',2)
            end
        end

        % Value changed function: XDropDown
        function XDropDownValueChanged(app, event)
            YDropDownValueChanged(app, event)         
        end

        % Cell edit callback: ConditionsTable
        function ConditionsTableCellEdit(app, event)
            YDropDownValueChanged(app, event)
            MinDatabase                = app.ConditionsTable.Data;
            SelectedData               = table2array(MinDatabase(:,6));
            SelectOn                   = find(SelectedData == 1);
            SelectedData               = MinDatabase(SelectOn,:);
            app.OperatingDatabase.Data = SelectedData;
            MinKMCutOffValueChanged(app)
            MaxKMCutOffValueChanged(app)
            
            DBCurrent                  = table2array(MinDatabase(:,1));                       % Current for datapoint
            DBSlope                    = table2array(MinDatabase(:,2));                       % Slope for datapoint
            Km                         = sind(DBSlope)/1e6./((DBCurrent/4.7884).^(1/0.5142)); % Magnetic Susceptibility for the current set
            MinDatabase(:,5)           = table(Km);
            app.ConditionsTable.Data   = MinDatabase;
        end

        % Button pushed function: RemoveDatapointButton
        function RemoveDatapointButtonPushed(app, event)
            RemoveIndex              = app.UIConitionsTableIndex;
            Data                     = app.ConditionsTable.Data;
            Data(RemoveIndex(1),:)   = [];
            app.ConditionsTable.Data = Data;
            YDropDownValueChanged(app, event)
        end

        % Cell selection callback: ConditionsTable
        function ConditionsTableCellSelection(app, event)
            indices = event.Indices;
            app.UIConitionsTableIndex=indices;
        end

        % Button pushed function: AddPhaseButton
        function AddPhaseButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DatabaseModificationTab;            
        end

        % Button pushed function: AddPhase
        function AddPhaseButtonPushed2(app, event)
            Data    = app.AddDatabaseTable.Data;
            Mineral = app.Name.Value;
            Select  = app.KmButton.Value;
            
            if Select == 1
                Max = app.MaxKm.Value;
                Min = app.MinKm.Value;
            else
                Max = app.MaxKm2.Value;
                Min = app.MinKm2.Value;
            end
            
            deleteVal = false;
            
            NewData      = Data(1,:);
            NewData      = [NewData;Data];
            NewData(1,:) = table({Mineral}, Min, Max, deleteVal);
            app.AddDatabaseTable.Data = NewData;
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
             Data   = app.AddDatabaseTable.Data;
             DelCol = table2array(Data(:,4));
             
             DelInd                    = find(DelCol==1);
             Data(DelInd,:)            = [];
             app.AddDatabaseTable.Data = Data;            
        end

        % Selection changed function: InputType
        function InputTypeSelectionChanged(app, event)
            Select = app.KmButton.Value;  
            
            if Select == 1
                app.MinKm.Enable = 1;
                app.MaxKm.Enable = 1;
                
                app.Current1.Enable    = 0;
                app.Current2.Enable    = 0;
                app.Slope1.Enable      = 0;
                app.Slope2.Enable      = 0;
                app.MinKm2.Enable      = 0;
                app.MaxKm2.Enable      = 0;
                app.CalculateKm.Enable = 0;
            else
                app.MinKm.Enable = 0;
                app.MaxKm.Enable = 0;
                
                app.Current1.Enable    = 1;
                app.Current2.Enable    = 1;
                app.Slope1.Enable      = 1;
                app.Slope2.Enable      = 1;
                app.MinKm2.Enable      = 1;
                app.MaxKm2.Enable      = 1;
                app.CalculateKm.Enable = 1;
            end
        end

        % Button pushed function: CalculateKm
        function CalculateKmButtonPushed(app, event)
            I1  = app.Current1.Value;
            I2  = app.Current2.Value;
            S1  = app.Slope1.Value;
            S2  = app.Slope2.Value;
            
            Km1 = sind(S1)/1e6/(I1/4.7884)^(1/0.5142);
            Km2 = sind(S2)/1e6/(I2/4.7884)^(1/0.5142);
            
            Km = [Km1,Km2];
            Km = sort(Km);
            
            app.MinKm2.Value = Km(1);
            app.MaxKm2.Value = Km(2);            
        end

        % Button pushed function: UpdateTables
        function UpdateTablesPushed(app, event)
            Database                   = app.AddDatabaseTable.Data;
            Database(:,4)              = table(false);
            Original                   = Database(:,1:3);
            app.Original_Database      = Original;
            app.DatabaseTable.Data     = Database;
            app.DatabaseTable_2.Data   = Database;
            app.DatabaseComposite.Data = Database;
            SettingChange(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            Selected     = app.SelectPhaseDropDown.Value;
            Data         = app.AddCompositeDatabase.Data;
            deleteVal    = false;
            
            if ~isempty(Data)
                NewData      = Data(1,:);
                NewData      = [NewData;Data];
                NewData(1,:) = table({Selected},0, deleteVal);
            else
                NewData      = table({Selected},0, deleteVal);
            end
            
            app.AddCompositeDatabase.Data = NewData;     
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed2(app, event)
            Data   = app.AddCompositeDatabase.Data;
            DelCol = table2array(Data(:,3));
            
            DelInd                        = find(DelCol==1);
            Data(DelInd,:)                = [];
            app.AddCompositeDatabase.Data = Data;
        end

        % Button pushed function: CreateComposite
        function CreateCompositeButtonPushed(app, event)
            Data             = app.Original_Database;
            OrginalPhases    = table2array(Data(:,1));
            Kmin             = table2array(Data(:,2));
            Kmax             = table2array(Data(:,3));
            CompostiteData   = app.AddCompositeDatabase.Data;
            CompositePhases  = table2array(CompostiteData(:,1));
            WeightPercent    = table2array(CompostiteData(:,2));
            TotalWeight      = sum(WeightPercent);
            NumberOfPhases   = size(CompositePhases,1);
            NormalizedWeight = WeightPercent/TotalWeight;
            
            for i=1:NumberOfPhases
               index(i)    = find(strcmp(OrginalPhases, CompositePhases(i)));
               KminComp(i) = Kmin(index(i));
               KmaxComp(i) = Kmax(index(i));
               Mineral(i)  =  CompositePhases(i);
            end

            NormalizedKmin = [KminComp'.*NormalizedWeight];
            NormalizedKmax = [KmaxComp'.*NormalizedWeight];
            CompKmin       = sum(NormalizedKmin);
            ComKmax        = sum(NormalizedKmax);
            
            Composite_Name = app.CompositeName.Value;
            deleteVal      = false;
            Data2          = app.DatabaseComposite.Data;           

            if ~isempty(Data2)
                NewData      = Data2(1,:);
                NewData      = [NewData;Data2];
                NewData(1,:) = table({Composite_Name},CompKmin, ComKmax, deleteVal);
            else
                NewData      = table({Composite_Name},CompKmin, ComKmax, deleteVal);
            end
            
            app.DatabaseComposite.Data = NewData;           
        end

        % Button pushed function: UpdateTables2
        function UpdateTables2ButtonPushed(app, event)
            Database                  = app.DatabaseComposite.Data;
            Database(:,4)             = table(false);
            Original                  = Database(:,1:3);
            app.Original_Database     = Original;
            app.DatabaseTable.Data    = Database;
            app.DatabaseTable_2.Data  = Database;
            app.AddDatabaseTable.Data = Database;
            SettingChange(app)
        end

        % Button pushed function: Delete_2
        function Delete_2ButtonPushed(app, event)
            Data   = app.DatabaseComposite.Data;
            DelCol = table2array(Data(:,4));
            
            DelInd                     = find(DelCol==1);
            Data(DelInd,:)             = [];
            app.DatabaseComposite.Data = Data;
        end

        % Value changed function: MaxKMCutOff
        function MaxKMCutOffValueChanged(app, event)
            MinDatabase      = app.OperatingDatabase.Data;
            
            if ~isempty(MinDatabase)
                DBCurrent   = table2array(MinDatabase(:,1));                       % Current for datapoint
                DBSlope     = table2array(MinDatabase(:,2));                       % Slope for datapoint
                MagPercent  = table2array(MinDatabase(:,3));                       % Percent of grains magnetic
                Km          = sind(DBSlope)/1e6./((DBCurrent/4.7884).^(1/0.5142)); % Magnetic Susceptibility for the current settings
                
                MaxCutOff           = app.MaxKMCutOff.Value;
                MaxCompare          = MagPercent-MaxCutOff;
                MaxCompare2         = MaxCompare;
                MaxCompare3         = -MaxCompare;
                NegVal              = find(MaxCompare2<0);
                MaxCompare2(NegVal) = NaN;
                [~,MaxIndex1]       = min(MaxCompare2);
                
                NegVal              = find(MaxCompare3<0);
                MaxCompare3(NegVal) = NaN;
                [~,MaxIndex2]       = min(MaxCompare3);
                
                mdl                 = fitlm(MagPercent(MaxIndex1:MaxIndex2),Km(MaxIndex1:MaxIndex2));
                MaxCoef             = mdl.Coefficients;
                m_Max               = table2array(MaxCoef(2,1));
                int_Max             = table2array(MaxCoef(1,1));
                KMaxInt             = m_Max*MaxCutOff+int_Max;
                
                app.MaxKmInt.Value  = KMaxInt;
            end
        end

        % Value changed function: MinKMCutOff
        function MinKMCutOffValueChanged(app, event)
            MinDatabase     = app.OperatingDatabase.Data;
            
            if ~isempty(MinDatabase)
                DBCurrent   = table2array(MinDatabase(:,1));                       % Current for datapoint
                DBSlope     = table2array(MinDatabase(:,2));                       % Slope for datapoint
                MagPercent  = table2array(MinDatabase(:,3));                       % Percent of grains magnetic
                Km          = sind(DBSlope)/1e6./((DBCurrent/4.7884).^(1/0.5142)); % Magnetic Susceptibility for the current settings
                
                MinCutOff           = app.MinKMCutOff.Value;
                MinCompare          = MagPercent-MinCutOff;
                MinCompare2         = MinCompare;
                MinCompare3         = -MinCompare;
                NegVal              = find(MinCompare2<0);
                MinCompare2(NegVal) = NaN;
                [~,MinIndex1]       = min(MinCompare2);
                
                NegVal              = find(MinCompare3<0);
                MinCompare3(NegVal) = NaN;
                [~,MinIndex2]       = min(MinCompare3);
                
                mdl                 = fitlm(MagPercent(MinIndex1:MinIndex2),Km(MinIndex1:MinIndex2));
                MinCoef             = mdl.Coefficients;
                m_Min               = table2array(MinCoef(2,1));
                int_Min             = table2array(MinCoef(1,1));
                KMinInt             = m_Min*MinCutOff+int_Min;
                
                app.MinKmInt.Value  = KMinInt;
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            d = dialog('Position',[300 300 250 150],'Name','Help');
            
            txt = uicontrol('Parent',d,...
                'Style','text',...
                'Position',[20 80 210 40],...
                'String','Export the current database and the phase behavior associated with Frantz settings into a .xlsx file');
            
            btn = uicontrol('Parent',d,...
                'Position',[85 20 70 25],...
                'String','Close',...
                'Callback','delete(gcf)');
        end

        % Button pushed function: MinIDExportButton
        function MinIDExportButtonPushed(app, event)
            Data                                 = app.DatabaseTable.Data;
            MagTable                             = app.Mag_Table.Data;
            MagTableCol                          = app.Mag_Table.ColumnName;
            MagTable                             = table(MagTable);
            MagTable.Properties.VariableNames    = MagTableCol;
            NonMagTable                          = app.NonMag_Table.Data;
            NonMagTableCol                       = app.NonMag_Table.ColumnName;
            NonMagTable                          = table(NonMagTable);
            NonMagTable.Properties.VariableNames = NonMagTableCol;
            CurrentVal                           = app.Current.Value;
            SlopeVal                             = app.Slope.Value;
            Km                                   = app.MagneticSusceptibility_Edit.Value;
            VarNames                             = app.DatabaseTable.ColumnName;
            Data.Properties.VariableNames        = VarNames;
            
            if ~isempty(Data)
                Conditions = table({'Current(A)'},{CurrentVal},{'Slope(degrees)'},{SlopeVal},{'Magnetic Susceptibility'},{Km});
                prompt     = {'Enter File Name'};
                dlg_title  = 'Save File';
                answer     = inputdlg(prompt,dlg_title);
                answer     = char(answer);
                FileName   = strcat(answer,'.xlsx');
                Directory  = uigetdir;
                cd(Directory)
                
                writetable(Data,FileName,'Sheet', 'Database','Range', 'A1','WriteVariableNames',1)
                writetable(Conditions,FileName,'Sheet', 'Phase Behavior','Range', 'A1','WriteVariableNames',0)
                writetable(MagTable,FileName,'Sheet', 'Phase Behavior','Range', 'A2','WriteVariableNames',1)
                writetable(NonMagTable,FileName,'Sheet', 'Phase Behavior','Range', 'B2','WriteVariableNames',1)
            end
        end

        % Button pushed function: WorkflowExport
        function WorkflowExportButtonPushed(app, event)
            Data                          = cell2table(app.Step_UITable.Data);
            VarNames                      = app.Step_UITable.ColumnName;
            Data.Properties.VariableNames = VarNames;
            
            if ~isempty(Data)
                prompt     = {'Enter File Name'};
                dlg_title  = 'Save File';
                answer     = inputdlg(prompt,dlg_title);
                answer     = char(answer);
                FileName   = strcat(answer,'.xlsx');
                Directory  = uigetdir;
                cd(Directory)
                
                writetable(Data,FileName,'Sheet', 'Workflow','Range', 'A1','WriteVariableNames',1)
            end
        end

        % Button pushed function: ExportMineralButton
        function ExportMineralButtonPushed(app, event)
            Data                          = app.ConditionsTable.Data;
            VarNames                      = app.ConditionsTable.ColumnName;
            Data.Properties.VariableNames = VarNames;

            MinCutoff  = app.MinKMCutOff.Value;
            MaxCutoff  = app.MaxKMCutOff.Value;
            Kmin       = app.MinKmInt.Value;
            Kmax       = app.MaxKmInt.Value;
            
            KValues = table({'Minimum Magnetic Susceptibility (Km)';Kmin},{'Maximum Magnetic Susceptibility (Km)';Kmax}...
                ,{'Minimum Cut-off Value Used';MinCutoff}, {'Maximum Cut-off Value Used';MaxCutoff});
           
            if ~isempty(Data)
                prompt     = {'Enter File Name'};
                dlg_title  = 'Save File';
                answer     = inputdlg(prompt,dlg_title);
                answer     = char(answer);
                FileName   = strcat(answer,'.xlsx');
                Directory  = uigetdir;
                cd(Directory)
                
                writetable(KValues,FileName,'Sheet', 'Km Values','Range', 'A1','WriteVariableNames',0)
                writetable(Data,FileName,'Sheet', 'Datapoints','Range', 'A1','WriteVariableNames',1)
            end
        end

        % Button pushed function: ExportAddPhase
        function ExportAddPhaseButtonPushed(app, event)
            Data                          = app.AddDatabaseTable.Data;
            Data(:,4)                     =[];
            VarNames                      = app.AddDatabaseTable.ColumnName;
            Data.Properties.VariableNames = VarNames(1:3);
            
            NewMineral = app.Name.Value;
            KmOn       = app.KmButton.Value;
            
            if KmOn == 1
                MinKmVal = app.MinKm.Value;
                MaxKmVal = app.MaxKm.Value;
                Inputs   = table({'Mineral'; NewMineral},{'Minimum Magnetic Susceptibility (Km)';MinKmVal},{'Maximum Magnetic Susceptibility (Km)';MaxKmVal});
            else
                MinKmVal    = app.MinKm2.Value;
                MaxKmVal    = app.MaxKm2.Value;
                Current1Val = app.Current1.Value;
                Current2Val = app.Current2.Value;
                Slope1Val   = app.Slope1.Value;
                Slope2Val   = app.Slope2.Value;
                Inputs      = table({'Mineral'; NewMineral},{'Minimum Magnetic Susceptibility (Km)';MinKmVal},{'Maximum Magnetic Susceptibility (Km)';MaxKmVal},...
                    {'Current 1'; Current1Val},{'Slope 1'; Slope1Val},{'Current 2'; Current2Val}, {'Slope 2'; Slope2Val});
            end

            if ~isempty(Data)
                prompt     = {'Enter File Name'};
                dlg_title  = 'Save File';
                answer     = inputdlg(prompt,dlg_title);
                answer     = char(answer);
                FileName   = strcat(answer,'.xlsx');
                Directory  = uigetdir;
                cd(Directory)
                
                writetable(Inputs,FileName,'Sheet', 'Last Inputs','Range', 'A1','WriteVariableNames',0)
                writetable(Data,FileName,'Sheet', 'Database','Range', 'A1','WriteVariableNames',1)
            end
        end

        % Button pushed function: CompositeExport
        function CompositeExportButtonPushed(app, event)
            Inputs                          = app.AddCompositeDatabase.Data;
            Inputs(:,3)                     =[];
            VarNames                        = app.AddCompositeDatabase.ColumnName;
            Inputs.Properties.VariableNames = VarNames(1:2);
            
            Data                            = app.DatabaseComposite.Data;
            Data(:,4)                       =[];
            VarNames                        = app.DatabaseComposite.ColumnName;
            Data.Properties.VariableNames   = VarNames(1:3);
            
            NewPhase                        = app.CompositeName.Value;
            
            if ~isempty(Data)
                prompt     = {'Enter File Name'};
                dlg_title  = 'Save File';
                answer     = inputdlg(prompt,dlg_title);
                answer     = char(answer);
                FileName   = strcat(answer,'.xlsx');
                Directory  = uigetdir;
                cd(Directory)
                
                writetable(Inputs,FileName,'Sheet', NewPhase, 'Range', 'A1','WriteVariableNames',1)
                writetable(Data,FileName,'Sheet', 'Database','Range', 'A1','WriteVariableNames',1)
            end
        end

        % Menu selected function: MinIDLoad
        function MinIDLoadSelected(app, event)
            helpdlg('Load a spreadsheet - Column 1 = Mineral Name - Column 2 = Minimum Km Value - Column 3 = Maximum Km Value');
        end

        % Menu selected function: MinIDAdd_Help
        function MinIDAdd_HelpMenuSelected(app, event)
            helpdlg('Add a new phase or create a new composite phase. Brings you to the "Database Modification" tab.')
        end

        % Menu selected function: MinIDData_Help
        function MinIDData_HelpMenuSelected(app, event)
            helpdlg(['Select what phases you want to view. The behavior of the selected phase' ...
                ' are shown graphically below or in the lists to the right based on current frantz conditions']);
        end

        % Menu selected function: MinIDKm_Help
        function MinIDKm_HelpMenuSelected(app, event)
            helpdlg(['The current boundary between "magnetic" and "non-magnetic" phases based on the current' ...
                ' inputted Frantz conditions.']);
        end

        % Menu selected function: MinIDMag_Help
        function MinIDMag_HelpMenuSelected(app, event)
            helpdlg('The magnetic phases based on the current inputted Frantz conditions.')
        end

        % Menu selected function: MinIDNonMag_Help
        function MinIDNonMag_HelpMenuSelected(app, event)
            helpdlg('The non-magnetic phases based on the current inputted Frantz conditions.')
        end

        % Menu selected function: MinIDCurrent_Help
        function MinIDCurrent_HelpMenuSelected(app, event)
            helpdlg('The current (in amps) of the Frantz.')
        end

        % Menu selected function: MinIDSlope_Help
        function MinIDSlope_HelpMenuSelected(app, event)
            helpdlg('The side slope (in degrees) of the Frantz chute.')
        end

        % Menu selected function: MinIDSelection_Help
        function MinIDSelection_HelpMenuSelected(app, event)
            helpdlg('Select the units that you wish data to be displayed in.')
        end

        % Menu selected function: MinIDHelp
        function MinIDHelpMenuSelected(app, event)
            helpdlg('Use this tab to visualize the magnetic behavior of minerals in a database.');
        end

        % Menu selected function: Work_Help
        function Work_HelpMenuSelected(app, event)
            helpdlg('Use this tab to determine a magnetic separation workflow.');
        end

        % Menu selected function: WorkLoad_Help
        function WorkLoad_HelpMenuSelected(app, event)
            helpdlg('Load a spreadsheet - Column 1 = Mineral Name - Column 2 = Minimum Km Value - Column 3 = Maximum Km Value');
        end

        % Menu selected function: WorkCalc_Help
        function WorkCalc_HelpMenuSelected(app, event)
            helpdlg('Calculate a magnetic separation workflow on the select phases.')

        end

        % Menu selected function: WorkMaxI_Help
        function WorkMaxI_HelpMenuSelected(app, event)
            helpdlg(['Set the maximum working conditions (current) for your workflow. ' ...
                'This is typically done to prevent overheating at high currents (e.g. > 1.7A).']);
        end

        % Menu selected function: WorkMode_Help
        function WorkMode_HelpMenuSelected(app, event)
            helpdlg(['Normal Mode - Extract low Km phases (high current) first. Reverse Mode - Extract high Km phases (low current...' ...
                ' first'])
        end

        % Menu selected function: WorkExport_Help
        function WorkExport_HelpMenuSelected(app, event)
            helpdlg('Export created magnetic separation workflow into a .xlsx spreadsheet.')
        end

        % Menu selected function: WorkSelect_Help
        function WorkSelect_HelpMenuSelected(app, event)
            helpdlg('Select the phases you wish to create your workflow with.')
        end

        % Menu selected function: Create_Help
        function Create_HelpMenuSelected(app, event)
            helpdlg('Use this tab to calculate the Km values of a mineral.')
        end

        % Menu selected function: CreateLoad_Help
        function CreateLoad_HelpMenuSelected(app, event)
            helpdlg(['Load a spreadsheet - Column 1 = Current - Column 2 = Slope - Column 3 = Weight % Magnetic Phase'...
                ' - Column 4 = Weight % Non-magnetic Phase']);
        end

        % Menu selected function: CreateExport_Help
        function CreateExport_HelpMenuSelected(app, event)
            helpdlg('Export the datatable into a .xlsx spreadsheet.')
        end

        % Menu selected function: CreateAdd_Help
        function CreateAdd_HelpMenuSelected(app, event)
            helpdlg(['Add another datapoint - requires the weight percent of phase located in the magnetic collection'...
               ' cup and non-magnetic collection cup for a given current and slope setting.'])
        end

        % Menu selected function: CreateMaxCut_Help
        function CreateMaxCut_HelpMenuSelected(app, event)
           helpdlg(['Select the maximum cut-off value (Mag %) to eliminate the tails at the high end of magnetic collection.'...
               ' It is suggested that you use the visualization Mag % vs. Km to aid in this selection.']) 
        end

        % Menu selected function: CreateMinCut_Help
        function CreateMinCut_HelpMenuSelected(app, event)
            helpdlg(['Select the minimum cut-off value (Mag %) to eliminate the tails at the low end of magnetic collection.'...
                ' It is suggested that you use the visualization Mag % vs Km to aid in this selection.']) 
        end

        % Menu selected function: CreateTable_Help
        function CreateTable_HelpMenuSelected(app, event)
            helpdlg('Use this datatable to select which datapoints you wish to visualize and calculate the Km values with.')
        end

        % Menu selected function: CreateMinK_Help
        function CreateMinK_HelpMenuSelected(app, event)
            helpdlg('The calculated minimum Km value based on the Min Mag(%) cutoff value and the selected datapoints.')
        end

        % Menu selected function: CreateMaxK_Help
        function CreateMaxK_HelpMenuSelected(app, event)
            helpdlg('The calculated maximum Km value based on the Max Mag(%) cutoff value and the selected datapoints.')            
        end

        % Menu selected function: Mod_Help
        function Mod_HelpMenuSelected(app, event)
            helpdlg('Use the blue tabs to add/delete new pure and composite phases to a database.')
        end

        % Menu selected function: ModAdd_Help
        function ModAdd_HelpMenuSelected(app, event)
            helpdlg('Use this tab to add or delete a phase to a database.')
        end

        % Menu selected function: ModAddType_Help
        function ModAddType_HelpMenuSelected(app, event)
            helpdlg(['Select which inputs to use for the new phase. Select "Km" to add in Km values directly' ...
                ' Select "Frantz Settings" to input the current/slope combination of the Frantz to calculate Km values.'])
        end

        % Menu selected function: ModAddMin_Help
        function ModAddMin_HelpMenuSelected(app, event)
            helpdlg('Enter the minimum Km value for the phase.')
        end

        % Menu selected function: ModAddMax_Help
        function ModAddMax_HelpMenuSelected(app, event)
            helpdlg('Enter the maximum Km value for the phase.')
        end

        % Menu selected function: ModC1_Help
        function ModC1_HelpMenuSelected(app, event)
            helpdlg('Enter the current used at the non-magnetic end of collection.')
        end

        % Menu selected function: ModS1_Help
        function ModS1_HelpMenuSelected(app, event)
            helpdlg('Enter the slope used at the non-magnetic end of collection.')
        end

        % Menu selected function: ModAddMin2_Help
        function ModAddMin2_HelpMenuSelected(app, event)
            helpdlg('The calculated Km value based on "Current 1" and "Slope 1".')
        end

        % Menu selected function: ModC2_Help
        function ModC2_HelpMenuSelected(app, event)
            helpdlg('Enter the current used at the magnetic end of collection.')
        end

        % Menu selected function: ModS2_Help
        function ModS2_HelpMenuSelected(app, event)
            helpdlg('Enter the slope used at the magnetic end of collection.')
        end

        % Menu selected function: ModAddMax2_Help
        function ModAddMax2_HelpMenuSelected(app, event)
           helpdlg('The calculated Km value based on "Current 2" and "Slope 2".') 
        end

        % Menu selected function: ModCalc_Help
        function ModCalc_HelpMenuSelected(app, event)
            helpdlg('Calculate the Km values based on inputted currents and slopes.')
        end

        % Menu selected function: ModPhase_Help
        function ModPhase_HelpMenuSelected(app, event)
            helpdlg('Add the new phase to the database.')
        end

        % Menu selected function: ModExport_Help
        function ModExport_HelpMenuSelected(app, event)
            helpdlg('Export the datatable and the most recently created phase into a .xlsx spreadsheet.')
        end

        % Menu selected function: ModUpdate_Help
        function ModUpdate_HelpMenuSelected(app, event)
            helpdlg('Update the current datatable used by MineralMate in all other tabs.')
        end

        % Menu selected function: CompSelect_Help
        function CompSelect_HelpMenuSelected(app, event)
            helpdlg(['Select a phase that you wish to make a composite grain from and click the "Add" button.'...
                ' The phases in this list are the ones from the loaded database.'])
        end

        % Menu selected function: Comp_Help
        function Comp_HelpMenuSelected(app, event)
            helpdlg('Use this tab to create a composite phase from multiple other phases.')
        end

        % Menu selected function: CompAdd_Help
        function CompAdd_HelpMenuSelected(app, event)
            helpdlg('Add the selected phase from the "Select Phase" dropdown.')
        end

        % Menu selected function: CompTable_Help
        function CompTable_HelpMenuSelected(app, event)
            helpdlg(['For each phase add the weight percent that the phase has in the composite phase.'...
                ' Make sure that the total of all phases equals 100%.'])
        end

        % Menu selected function: CompCombine_Help
        function CompCombine_HelpMenuSelected(app, event)
            helpdlg(['From the selected phases and inputted weight (%) calculate the associated Km value of the'...
                ' composite phase.'])
        end

        % Menu selected function: CompUpdate_Help
        function CompUpdate_HelpMenuSelected(app, event)
            helpdlg('Update the current datatable used by MineralMate in all other tabs.')
        end

        % Menu selected function: CompExport_Help
        function CompExport_HelpMenuSelected(app, event)
            helpdlg('Export the datatable and the most recently created composite phase into a .xlsx spreadsheet.')
        end

        % Menu selected function: CreateRemove_Help
        function CreateRemove_HelpMenuSelected(app, event)
            helpdlg('Highlight the row that you wish to delete and click ''Remove Datapoint''.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MineralIdentificationTab = uitab(app.TabGroup);
            app.MineralIdentificationTab.Title = 'Mineral Identification';
            app.UIAxes = uiaxes(app.MineralIdentificationTab);
            xlabel(app.UIAxes, 'Extraction Range')
            app.CurrentASpinnerLabel = uilabel(app.MineralIdentificationTab);
            app.CurrentASpinnerLabel.Text = 'Current (A)';
            app.Current = uispinner(app.MineralIdentificationTab);
            app.Current.Step = 0.05;
            app.Current.Limits = [0 Inf];
            app.Current.ValueChangedFcn = createCallbackFcn(app, @CurrentChange, true);
            app.Current.Value = 1;
            app.SideAngleSpinnerLabel = uilabel(app.MineralIdentificationTab);
            app.SideAngleSpinnerLabel.Text = 'Side Angle';
            app.Slope = uispinner(app.MineralIdentificationTab);
            app.Slope.Limits = [-Inf 90];
            app.Slope.ValueChangedFcn = createCallbackFcn(app, @SlopeChange, true);
            app.Slope.Value = 15;
            app.DatabaseTable = uitable(app.MineralIdentificationTab);
            app.DatabaseTable.ColumnName = {'Mineral'; 'Min Current (A)'; 'Max Current (A)'; 'Select'};
            app.DatabaseTable.RowName = {};
            app.DatabaseTable.ColumnSortable = [false true true false];
            app.DatabaseTable.ColumnEditable = [false false false true];
            app.DatabaseTable.CellEditCallback = createCallbackFcn(app, @DatabaseTableCellEdit, true);
            app.AddPhaseButton = uibutton(app.MineralIdentificationTab, 'push');
            app.AddPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddPhaseButtonPushed, true);
            app.AddPhaseButton.Text = 'Add Phase';
            app.SettingGroup = uibuttongroup(app.MineralIdentificationTab);
            app.SettingGroup.SelectionChangedFcn = createCallbackFcn(app, @SettingChange, true);
            app.CurrentButton = uiradiobutton(app.SettingGroup);
            app.CurrentButton.Text = 'Current';
            app.CurrentButton.Value = true;
            app.SlopeButton = uiradiobutton(app.SettingGroup);
            app.SlopeButton.Text = 'Slope';
            app.SusceptibilityButton = uiradiobutton(app.SettingGroup);
            app.SusceptibilityButton.Text = 'Susceptibility';
            app.Mag_Table = uitable(app.MineralIdentificationTab);
            app.Mag_Table.ColumnName = {'Magnetic Minerals'};
            app.Mag_Table.RowName = {};
            app.LoadDatabaseButton = uibutton(app.MineralIdentificationTab, 'push');
            app.LoadDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatabase, true);
            app.LoadDatabaseButton.Text = 'Load Database';
            app.MagneticSusceptibilityLabel = uilabel(app.MineralIdentificationTab);
            app.MagneticSusceptibilityLabel.Text = 'Magnetic Susceptibility';
            app.MagneticSusceptibility_Edit = uieditfield(app.MineralIdentificationTab, 'numeric');
            app.MagneticSusceptibility_Edit.Editable = 'off';
            app.MagneticSusceptibility_Edit.Value = 1.301e-05;
            app.NonMag_Table = uitable(app.MineralIdentificationTab);
            app.NonMag_Table.ColumnName = {'Non-Magnetic Minerals'};
            app.NonMag_Table.RowName = {};
            app.MinIDExportButton = uibutton(app.MineralIdentificationTab, 'push');
            app.MinIDExportButton.ButtonPushedFcn = createCallbackFcn(app, @MinIDExportButtonPushed, true);
            app.MinIDExportButton.Text = 'Export';
            app.WorkflowSuggestionTab = uitab(app.TabGroup);
            app.WorkflowSuggestionTab.Title = 'Workflow Suggestion';
            app.DatabaseTable_2 = uitable(app.WorkflowSuggestionTab);
            app.DatabaseTable_2.ColumnName = {'Mineral'; 'Min Current'; 'Max Current'; 'Select'};
            app.DatabaseTable_2.RowName = {};
            app.DatabaseTable_2.ColumnSortable = [false false];
            app.DatabaseTable_2.ColumnEditable = [false false false true];
            app.LoadDatabaseButton_2 = uibutton(app.WorkflowSuggestionTab, 'push');
            app.LoadDatabaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadDatabaseButton_2Pushed, true);
            app.LoadDatabaseButton_2.Text = 'Load Database';
            app.Step_UITable = uitable(app.WorkflowSuggestionTab);
            app.Step_UITable.ColumnName = {'Step'; 'Current'; 'Slope'; 'Mag Minerals (Process)'; 'Non-Mag Minerals (Collect)'};
            app.Step_UITable.RowName = {};
            app.CalculateButton = uibutton(app.WorkflowSuggestionTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaxCurrentAEditFieldLabel = uilabel(app.WorkflowSuggestionTab);
            app.MaxCurrentAEditFieldLabel.Text = 'Max Current (A)';
            app.MaxCurrentAEditField = uieditfield(app.WorkflowSuggestionTab, 'numeric');
            app.MaxCurrentAEditField.Value = 1.5;
            app.SelectAllCheckBox = uicheckbox(app.WorkflowSuggestionTab);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Text = 'Select All';
            app.ProcedureTextAreaLabel = uilabel(app.WorkflowSuggestionTab);
            app.ProcedureTextAreaLabel.Text = 'Procedure';
            app.ProcedureTextArea = uitextarea(app.WorkflowSuggestionTab);
            app.ProcedureTextArea.Value = {'1) Select the phases you wish to process'; '2) Set the maximum current the frantz will run at.'; '3) Select "normal" to process from high to low current or select "Reverse" to process from low to high current.'; '3) Click Calculate.'; '4) Collect Non-Mag (for "normal" setting) mineral separates.'; '5) Further process the Mag separates (for "normal" setting) until the mineral of interest is collected.'};
            app.ButtonGroup = uibuttongroup(app.WorkflowSuggestionTab);
            app.NormalButton = uiradiobutton(app.ButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.ReverseButton = uiradiobutton(app.ButtonGroup);
            app.ReverseButton.Text = 'Reverse';
            app.WorkflowExport = uibutton(app.WorkflowSuggestionTab, 'push');
            app.WorkflowExport.ButtonPushedFcn = createCallbackFcn(app, @WorkflowExportButtonPushed, true);
            app.WorkflowExport.Text = 'Export';
            app.MineralCreationTab = uitab(app.TabGroup);
            app.MineralCreationTab.Title = 'Mineral Creation';
            app.UIAxes2 = uiaxes(app.MineralCreationTab);
            app.LoadMineralButton = uibutton(app.MineralCreationTab, 'push');
            app.LoadMineralButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMineralButtonPushed, true);
            app.LoadMineralButton.Text = 'Load Mineral';
            app.ConditionsTable = uitable(app.MineralCreationTab);
            app.ConditionsTable.ColumnName = {'Current'; 'Slope'; 'Mag (%)'; 'Non-Mag (%)'; 'Km'; 'Select'};
            app.ConditionsTable.RowName = {};
            app.ConditionsTable.ColumnEditable = [true true true true false true];
            app.ConditionsTable.CellEditCallback = createCallbackFcn(app, @ConditionsTableCellEdit, true);
            app.ConditionsTable.CellSelectionCallback = createCallbackFcn(app, @ConditionsTableCellSelection, true);
            app.ExportMineralButton = uibutton(app.MineralCreationTab, 'push');
            app.ExportMineralButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMineralButtonPushed, true);
            app.ExportMineralButton.Text = 'Export Mineral';
            app.AddDatapointButton = uibutton(app.MineralCreationTab, 'push');
            app.AddDatapointButton.ButtonPushedFcn = createCallbackFcn(app, @AddDatapointButtonPushed, true);
            app.AddDatapointButton.Text = 'Add Datapoint';
            app.RemoveDatapointButton = uibutton(app.MineralCreationTab, 'push');
            app.RemoveDatapointButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDatapointButtonPushed, true);
            app.RemoveDatapointButton.Text = 'Remove Datapoint';
            app.YDropDown = uidropdown(app.MineralCreationTab);
            app.YDropDown.Items = {'Mag(%)', 'NonMag(%)', 'Current', 'Slope', 'Km'};
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @YDropDownValueChanged, true);
            app.YDropDown.Value = 'Mag(%)';
            app.XDropDown = uidropdown(app.MineralCreationTab);
            app.XDropDown.Items = {'Current', 'Slope', 'Mag(%)', 'NonMag(%)', 'Km'};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.XDropDown.Value = 'Current';
            app.MaxKMCutOff = uieditfield(app.MineralCreationTab, 'numeric');
            app.MaxKMCutOff.ValueChangedFcn = createCallbackFcn(app, @MaxKMCutOffValueChanged, true);
            app.MaxKMCutOff.Value = 90;
            app.MinKMCutOff = uieditfield(app.MineralCreationTab, 'numeric');
            app.MinKMCutOff.ValueChangedFcn = createCallbackFcn(app, @MinKMCutOffValueChanged, true);
            app.MinKMCutOff.Value = 10;
            app.MaxKmEditFieldLabel_3 = uilabel(app.MineralCreationTab);
            app.MaxKmEditFieldLabel_3.Text = 'Max Km';
            app.MaxKmInt = uieditfield(app.MineralCreationTab, 'numeric');
            app.MaxKmInt.Editable = 'off';
            app.MinKmEditFieldLabel_3 = uilabel(app.MineralCreationTab);
            app.MinKmEditFieldLabel_3.Text = 'Min Km';
            app.MinKmInt = uieditfield(app.MineralCreationTab, 'numeric');
            app.MinKmInt.Editable = 'off';
            app.MaxMagCutoffLabel = uilabel(app.MineralCreationTab);
            app.MaxMagCutoffLabel.Text = 'Max Mag(%) Cut-off';
            app.MinMagCutoffLabel = uilabel(app.MineralCreationTab);
            app.MinMagCutoffLabel.Text = 'Min Mag(%) Cut-off';
            app.DatabaseModificationTab = uitab(app.TabGroup);
            app.DatabaseModificationTab.Title = 'Database Modification';
            app.TabGroup2 = uitabgroup(app.DatabaseModificationTab);
            app.AddDeletePhaseTab = uitab(app.TabGroup2);
            app.AddDeletePhaseTab.Title = 'Add / Delete Phase';
            app.InputType = uibuttongroup(app.AddDeletePhaseTab);
            app.InputType.SelectionChangedFcn = createCallbackFcn(app, @InputTypeSelectionChanged, true);
            app.InputType.Title = 'Input Type';
            app.KmButton = uiradiobutton(app.InputType);
            app.KmButton.Text = 'Km';
            app.KmButton.Value = true;
            app.FrantzSettingsButton = uiradiobutton(app.InputType);
            app.FrantzSettingsButton.Text = 'Frantz Settings';
            app.MinKmEditField_3Label = uilabel(app.InputType);
            app.MinKmEditField_3Label.Text = 'Min Km';
            app.MinKm = uieditfield(app.InputType, 'numeric');
            app.MaxKmEditField_3Label = uilabel(app.InputType);
            app.MaxKmEditField_3Label.Text = 'Max Km';
            app.MaxKm = uieditfield(app.InputType, 'numeric');
            app.CalculateKm = uibutton(app.InputType, 'push');
            app.CalculateKm.ButtonPushedFcn = createCallbackFcn(app, @CalculateKmButtonPushed, true);
            app.CalculateKm.Enable = 'off';
            app.CalculateKm.Text = 'Calculate Km';
            app.Current1EditFieldLabel = uilabel(app.InputType);
            app.Current1EditFieldLabel.Text = 'Current 1';
            app.Current1 = uieditfield(app.InputType, 'numeric');
            app.Current1.Enable = 'off';
            app.Slope1EditFieldLabel = uilabel(app.InputType);
            app.Slope1EditFieldLabel.Text = 'Slope 1';
            app.Slope1 = uieditfield(app.InputType, 'numeric');
            app.Slope1.Enable = 'off';
            app.MinKmEditFieldLabel_2 = uilabel(app.InputType);
            app.MinKmEditFieldLabel_2.Text = 'Min Km';
            app.MinKm2 = uieditfield(app.InputType, 'numeric');
            app.MinKm2.Editable = 'off';
            app.MinKm2.Enable = 'off';
            app.Current2EditFieldLabel = uilabel(app.InputType);
            app.Current2EditFieldLabel.Text = 'Current 2';
            app.Current2 = uieditfield(app.InputType, 'numeric');
            app.Current2.Enable = 'off';
            app.Slope2EditFieldLabel = uilabel(app.InputType);
            app.Slope2EditFieldLabel.Text = 'Slope 2';
            app.Slope2 = uieditfield(app.InputType, 'numeric');
            app.Slope2.Enable = 'off';
            app.MaxKmEditFieldLabel_2 = uilabel(app.InputType);
            app.MaxKmEditFieldLabel_2.Text = 'Max Km';
            app.MaxKm2 = uieditfield(app.InputType, 'numeric');
            app.MaxKm2.Editable = 'off';
            app.MaxKm2.Enable = 'off';
            app.AddDatabaseTable = uitable(app.AddDeletePhaseTab);
            app.AddDatabaseTable.ColumnName = {'Mineral'; 'Min Km'; 'Max Km'; 'Delete?'};
            app.AddDatabaseTable.RowName = {};
            app.AddDatabaseTable.ColumnSortable = [false true true false];
            app.AddDatabaseTable.ColumnEditable = [false false false true];
            app.AddPhase = uibutton(app.AddDeletePhaseTab, 'push');
            app.AddPhase.ButtonPushedFcn = createCallbackFcn(app, @AddPhaseButtonPushed2, true);
            app.AddPhase.Text = 'Add Phase';
            app.Delete = uibutton(app.AddDeletePhaseTab, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Delete Selected';
            app.UpdateTables = uibutton(app.AddDeletePhaseTab, 'push');
            app.UpdateTables.ButtonPushedFcn = createCallbackFcn(app, @UpdateTablesPushed, true);
            app.UpdateTables.Text = 'Update Tables';
            app.PhaseNameLabel = uilabel(app.AddDeletePhaseTab);
            app.PhaseNameLabel.Text = 'Phase Name';
            app.Name = uieditfield(app.AddDeletePhaseTab, 'text');
            app.ExportAddPhase = uibutton(app.AddDeletePhaseTab, 'push');
            app.ExportAddPhase.ButtonPushedFcn = createCallbackFcn(app, @ExportAddPhaseButtonPushed, true);
            app.ExportAddPhase.Text = 'Export';
            app.AddCompositePhaseTab = uitab(app.TabGroup2);
            app.AddCompositePhaseTab.Title = 'Add Composite Phase ';
            app.SelectPhaseDropDownLabel = uilabel(app.AddCompositePhaseTab);
            app.SelectPhaseDropDownLabel.Text = 'Select Phase';
            app.SelectPhaseDropDown = uidropdown(app.AddCompositePhaseTab);
            app.DatabaseComposite = uitable(app.AddCompositePhaseTab);
            app.DatabaseComposite.ColumnName = {'Mineral'; 'Min Km'; 'Max Km'; 'Delete?'};
            app.DatabaseComposite.RowName = {};
            app.DatabaseComposite.ColumnSortable = [false true true false];
            app.DatabaseComposite.ColumnEditable = [false false false true];
            app.Delete_2 = uibutton(app.AddCompositePhaseTab, 'push');
            app.Delete_2.ButtonPushedFcn = createCallbackFcn(app, @Delete_2ButtonPushed, true);
            app.Delete_2.Text = 'Delete ';
            app.UpdateTables2 = uibutton(app.AddCompositePhaseTab, 'push');
            app.UpdateTables2.ButtonPushedFcn = createCallbackFcn(app, @UpdateTables2ButtonPushed, true);
            app.UpdateTables2.Text = 'Update Tables';
            app.AddButton = uibutton(app.AddCompositePhaseTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddCompositeDatabase = uitable(app.AddCompositePhaseTab);
            app.AddCompositeDatabase.ColumnName = {'Mineral'; 'Weight (%)'; 'Delete?'};
            app.AddCompositeDatabase.RowName = {};
            app.AddCompositeDatabase.ColumnSortable = [false true false];
            app.AddCompositeDatabase.ColumnEditable = [false true true];
            app.DeleteButton = uibutton(app.AddCompositePhaseTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed2, true);
            app.DeleteButton.Text = 'Delete';
            app.CreateComposite = uibutton(app.AddCompositePhaseTab, 'push');
            app.CreateComposite.ButtonPushedFcn = createCallbackFcn(app, @CreateCompositeButtonPushed, true);
            app.CreateComposite.Text = 'Combine and Create';
            app.PhaseNameEditFieldLabel = uilabel(app.AddCompositePhaseTab);
            app.PhaseNameEditFieldLabel.Text = 'Phase Name';
            app.CompositeName = uieditfield(app.AddCompositePhaseTab, 'text');
            app.CompositeExport = uibutton(app.AddCompositePhaseTab, 'push');
            app.CompositeExport.ButtonPushedFcn = createCallbackFcn(app, @CompositeExportButtonPushed, true);
            app.CompositeExport.Text = 'Export ';
            app.MinIDExportHelp = uicontextmenu(app.UIFigure);
            app.HelpMenu = uimenu(app.MinIDExportHelp);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            % Assign app.MinIDExportHelp
            app.MinIDExportButton.ContextMenu = app.MinIDExportHelp;
            app.MinIDLoadHelp = uicontextmenu(app.UIFigure);
            app.MinIDLoad = uimenu(app.MinIDLoadHelp);
            app.MinIDLoad.MenuSelectedFcn = createCallbackFcn(app, @MinIDLoadSelected, true);
            app.MinIDLoad.Text = 'Help';
            % Assign app.MinIDLoadHelp
            app.LoadDatabaseButton.ContextMenu = app.MinIDLoadHelp;
            app.MinIDAdd = uicontextmenu(app.UIFigure);
            app.MinIDAdd_Help = uimenu(app.MinIDAdd);
            app.MinIDAdd_Help.MenuSelectedFcn = createCallbackFcn(app, @MinIDAdd_HelpMenuSelected, true);
            app.MinIDAdd_Help.Text = 'Help';
            % Assign app.MinIDAdd
            app.AddPhaseButton.ContextMenu = app.MinIDAdd;
            app.MinIDData = uicontextmenu(app.UIFigure);
            app.MinIDData_Help = uimenu(app.MinIDData);
            app.MinIDData_Help.MenuSelectedFcn = createCallbackFcn(app, @MinIDData_HelpMenuSelected, true);
            app.MinIDData_Help.Text = 'Help';
            % Assign app.MinIDData
            app.DatabaseTable.ContextMenu = app.MinIDData;
            app.MinIDKm = uicontextmenu(app.UIFigure);
            app.MinIDKm_Help = uimenu(app.MinIDKm);
            app.MinIDKm_Help.MenuSelectedFcn = createCallbackFcn(app, @MinIDKm_HelpMenuSelected, true);
            app.MinIDKm_Help.Text = 'Help';
            % Assign app.MinIDKm
            app.MagneticSusceptibility_Edit.ContextMenu = app.MinIDKm;
            app.MinIDMag = uicontextmenu(app.UIFigure);
            app.MinIDMag_Help = uimenu(app.MinIDMag);
            app.MinIDMag_Help.MenuSelectedFcn = createCallbackFcn(app, @MinIDMag_HelpMenuSelected, true);
            app.MinIDMag_Help.Text = 'Help';
            % Assign app.MinIDMag
            app.Mag_Table.ContextMenu = app.MinIDMag;
            app.MinIDNonMag = uicontextmenu(app.UIFigure);
            app.MinIDNonMag_Help = uimenu(app.MinIDNonMag);
            app.MinIDNonMag_Help.MenuSelectedFcn = createCallbackFcn(app, @MinIDNonMag_HelpMenuSelected, true);
            app.MinIDNonMag_Help.Text = 'Help';
            % Assign app.MinIDNonMag
            app.NonMag_Table.ContextMenu = app.MinIDNonMag;
            app.MinIDCurrent = uicontextmenu(app.UIFigure);
            app.MinIDCurrent_Help = uimenu(app.MinIDCurrent);
            app.MinIDCurrent_Help.MenuSelectedFcn = createCallbackFcn(app, @MinIDCurrent_HelpMenuSelected, true);
            app.MinIDCurrent_Help.Text = 'Help';
            % Assign app.MinIDCurrent
            app.Current.ContextMenu = app.MinIDCurrent;
            app.MinIDSlope = uicontextmenu(app.UIFigure);
            app.MinIDSlope_Help = uimenu(app.MinIDSlope);
            app.MinIDSlope_Help.MenuSelectedFcn = createCallbackFcn(app, @MinIDSlope_HelpMenuSelected, true);
            app.MinIDSlope_Help.Text = 'Help';
            % Assign app.MinIDSlope
            app.Slope.ContextMenu = app.MinIDSlope;
            app.MinIDSelection = uicontextmenu(app.UIFigure);
            app.MinIDSelection_Help = uimenu(app.MinIDSelection);
            app.MinIDSelection_Help.MenuSelectedFcn = createCallbackFcn(app, @MinIDSelection_HelpMenuSelected, true);
            app.MinIDSelection_Help.Text = 'Help';
            % Assign app.MinIDSelection
            app.SettingGroup.ContextMenu = app.MinIDSelection;
            app.MinID = uicontextmenu(app.UIFigure);
            app.MinIDHelp = uimenu(app.MinID);
            app.MinIDHelp.MenuSelectedFcn = createCallbackFcn(app, @MinIDHelpMenuSelected, true);
            app.MinIDHelp.Text = 'Help';
            % Assign app.MinID
            app.MineralIdentificationTab.ContextMenu = app.MinID;
            app.Work = uicontextmenu(app.UIFigure);
            app.Work_Help = uimenu(app.Work);
            app.Work_Help.MenuSelectedFcn = createCallbackFcn(app, @Work_HelpMenuSelected, true);
            app.Work_Help.Text = 'Help';
            % Assign app.Work
            app.WorkflowSuggestionTab.ContextMenu = app.Work;
            app.WorkLoad = uicontextmenu(app.UIFigure);
            app.WorkLoad_Help = uimenu(app.WorkLoad);
            app.WorkLoad_Help.MenuSelectedFcn = createCallbackFcn(app, @WorkLoad_HelpMenuSelected, true);
            app.WorkLoad_Help.Text = 'Help';
            % Assign app.WorkLoad
            app.LoadDatabaseButton_2.ContextMenu = app.WorkLoad;
            app.WorkCalc = uicontextmenu(app.UIFigure);
            app.WorkCalc_Help = uimenu(app.WorkCalc);
            app.WorkCalc_Help.MenuSelectedFcn = createCallbackFcn(app, @WorkCalc_HelpMenuSelected, true);
            app.WorkCalc_Help.Text = 'Help';
            % Assign app.WorkCalc
            app.CalculateButton.ContextMenu = app.WorkCalc;
            app.WorkMaxI = uicontextmenu(app.UIFigure);
            app.WorkMaxI_Help = uimenu(app.WorkMaxI);
            app.WorkMaxI_Help.MenuSelectedFcn = createCallbackFcn(app, @WorkMaxI_HelpMenuSelected, true);
            app.WorkMaxI_Help.Text = 'Help';
            % Assign app.WorkMaxI
            app.MaxCurrentAEditField.ContextMenu = app.WorkMaxI;
            app.WorkMode = uicontextmenu(app.UIFigure);
            app.WorkMode_Help = uimenu(app.WorkMode);
            app.WorkMode_Help.MenuSelectedFcn = createCallbackFcn(app, @WorkMode_HelpMenuSelected, true);
            app.WorkMode_Help.Text = 'Help';
            % Assign app.WorkMode
            app.ButtonGroup.ContextMenu = app.WorkMode;
            app.NormalButton.ContextMenu = app.WorkMode;
            app.ReverseButton.ContextMenu = app.WorkMode;
            app.WorkExport = uicontextmenu(app.UIFigure);
            app.WorkExport_Help = uimenu(app.WorkExport);
            app.WorkExport_Help.MenuSelectedFcn = createCallbackFcn(app, @WorkExport_HelpMenuSelected, true);
            app.WorkExport_Help.Text = 'Help';
            % Assign app.WorkExport
            app.WorkflowExport.ContextMenu = app.WorkExport;
            app.WorkSelect = uicontextmenu(app.UIFigure);
            app.WorkSelect_Help = uimenu(app.WorkSelect);
            app.WorkSelect_Help.MenuSelectedFcn = createCallbackFcn(app, @WorkSelect_HelpMenuSelected, true);
            app.WorkSelect_Help.Text = 'Help';
            % Assign app.WorkSelect
            app.DatabaseTable_2.ContextMenu = app.WorkSelect;
            app.Create = uicontextmenu(app.UIFigure);
            app.Create_Help = uimenu(app.Create);
            app.Create_Help.MenuSelectedFcn = createCallbackFcn(app, @Create_HelpMenuSelected, true);
            app.Create_Help.Text = 'Help';
            % Assign app.Create
            app.MineralCreationTab.ContextMenu = app.Create;
            app.CreateLoad = uicontextmenu(app.UIFigure);
            app.CreateLoad_Help = uimenu(app.CreateLoad);
            app.CreateLoad_Help.MenuSelectedFcn = createCallbackFcn(app, @CreateLoad_HelpMenuSelected, true);
            app.CreateLoad_Help.Text = 'Help';
            % Assign app.CreateLoad
            app.LoadMineralButton.ContextMenu = app.CreateLoad;
            app.CreateExport = uicontextmenu(app.UIFigure);
            app.CreateExport_Help = uimenu(app.CreateExport);
            app.CreateExport_Help.MenuSelectedFcn = createCallbackFcn(app, @CreateExport_HelpMenuSelected, true);
            app.CreateExport_Help.Text = 'Help';
            % Assign app.CreateExport
            app.ExportMineralButton.ContextMenu = app.CreateExport;
            app.CreateAdd = uicontextmenu(app.UIFigure);
            app.CreateAdd_Help = uimenu(app.CreateAdd);
            app.CreateAdd_Help.MenuSelectedFcn = createCallbackFcn(app, @CreateAdd_HelpMenuSelected, true);
            app.CreateAdd_Help.Text = 'Help';
            % Assign app.CreateAdd
            app.AddDatapointButton.ContextMenu = app.CreateAdd;
            app.CreateMaxCut = uicontextmenu(app.UIFigure);
            app.CreateMaxCut_Help = uimenu(app.CreateMaxCut);
            app.CreateMaxCut_Help.MenuSelectedFcn = createCallbackFcn(app, @CreateMaxCut_HelpMenuSelected, true);
            app.CreateMaxCut_Help.Text = 'Help';
            % Assign app.CreateMaxCut
            app.MaxMagCutoffLabel.ContextMenu = app.CreateMaxCut;
            app.CreateMinCut = uicontextmenu(app.UIFigure);
            app.CreateMinCut_Help = uimenu(app.CreateMinCut);
            app.CreateMinCut_Help.MenuSelectedFcn = createCallbackFcn(app, @CreateMinCut_HelpMenuSelected, true);
            app.CreateMinCut_Help.Text = 'Help';
            % Assign app.CreateMinCut
            app.MinMagCutoffLabel.ContextMenu = app.CreateMinCut;
            app.CreateTable = uicontextmenu(app.UIFigure);
            app.CreateTable_Help = uimenu(app.CreateTable);
            app.CreateTable_Help.MenuSelectedFcn = createCallbackFcn(app, @CreateTable_HelpMenuSelected, true);
            app.CreateTable_Help.Text = 'Help';
            % Assign app.CreateTable
            app.ConditionsTable.ContextMenu = app.CreateTable;
            app.CreateMinK = uicontextmenu(app.UIFigure);
            app.CreateMinK_Help = uimenu(app.CreateMinK);
            app.CreateMinK_Help.MenuSelectedFcn = createCallbackFcn(app, @CreateMinK_HelpMenuSelected, true);
            app.CreateMinK_Help.Text = 'Help';
            % Assign app.CreateMinK
            app.MinKmEditFieldLabel_3.ContextMenu = app.CreateMinK;
            app.CreateMaxK = uicontextmenu(app.UIFigure);
            app.CreateMaxK_Help = uimenu(app.CreateMaxK);
            app.CreateMaxK_Help.MenuSelectedFcn = createCallbackFcn(app, @CreateMaxK_HelpMenuSelected, true);
            app.CreateMaxK_Help.Text = 'Help';
            % Assign app.CreateMaxK
            app.MaxKmEditFieldLabel_3.ContextMenu = app.CreateMaxK;
            app.Mod = uicontextmenu(app.UIFigure);
            app.Mod_Help = uimenu(app.Mod);
            app.Mod_Help.MenuSelectedFcn = createCallbackFcn(app, @Mod_HelpMenuSelected, true);
            app.Mod_Help.Text = 'Help';
            % Assign app.Mod
            app.TabGroup2.ContextMenu = app.Mod;
            app.ModAdd = uicontextmenu(app.UIFigure);
            app.ModAdd_Help = uimenu(app.ModAdd);
            app.ModAdd_Help.MenuSelectedFcn = createCallbackFcn(app, @ModAdd_HelpMenuSelected, true);
            app.ModAdd_Help.Text = 'Help';
            % Assign app.ModAdd
            app.AddDeletePhaseTab.ContextMenu = app.ModAdd;
            app.ModAddType = uicontextmenu(app.UIFigure);
            app.ModAddType_Help = uimenu(app.ModAddType);
            app.ModAddType_Help.MenuSelectedFcn = createCallbackFcn(app, @ModAddType_HelpMenuSelected, true);
            app.ModAddType_Help.Text = 'Help';
            % Assign app.ModAddType
            app.InputType.ContextMenu = app.ModAddType;
            app.ModAddMin = uicontextmenu(app.UIFigure);
            app.ModAddMin_Help = uimenu(app.ModAddMin);
            app.ModAddMin_Help.MenuSelectedFcn = createCallbackFcn(app, @ModAddMin_HelpMenuSelected, true);
            app.ModAddMin_Help.Text = 'Help';
            % Assign app.ModAddMin
            app.MinKmEditField_3Label.ContextMenu = app.ModAddMin;
            app.ModAddMax = uicontextmenu(app.UIFigure);
            app.ModAddMax_Help = uimenu(app.ModAddMax);
            app.ModAddMax_Help.MenuSelectedFcn = createCallbackFcn(app, @ModAddMax_HelpMenuSelected, true);
            app.ModAddMax_Help.Text = 'Help';
            % Assign app.ModAddMax
            app.MaxKmEditField_3Label.ContextMenu = app.ModAddMax;
            app.ModC1 = uicontextmenu(app.UIFigure);
            app.ModC1_Help = uimenu(app.ModC1);
            app.ModC1_Help.MenuSelectedFcn = createCallbackFcn(app, @ModC1_HelpMenuSelected, true);
            app.ModC1_Help.Text = 'Help';
            % Assign app.ModC1
            app.Current1EditFieldLabel.ContextMenu = app.ModC1;
            app.ModS1 = uicontextmenu(app.UIFigure);
            app.ModS1_Help = uimenu(app.ModS1);
            app.ModS1_Help.MenuSelectedFcn = createCallbackFcn(app, @ModS1_HelpMenuSelected, true);
            app.ModS1_Help.Text = 'Help';
            % Assign app.ModS1
            app.Slope1EditFieldLabel.ContextMenu = app.ModS1;
            app.Slope1.ContextMenu = app.ModS1;
            app.ModAddMin2 = uicontextmenu(app.UIFigure);
            app.ModAddMin2_Help = uimenu(app.ModAddMin2);
            app.ModAddMin2_Help.MenuSelectedFcn = createCallbackFcn(app, @ModAddMin2_HelpMenuSelected, true);
            app.ModAddMin2_Help.Text = 'Help';
            % Assign app.ModAddMin2
            app.MinKmEditFieldLabel_2.ContextMenu = app.ModAddMin2;
            app.MinKm2.ContextMenu = app.ModAddMin2;
            app.ModC2 = uicontextmenu(app.UIFigure);
            app.ModC2_Help = uimenu(app.ModC2);
            app.ModC2_Help.MenuSelectedFcn = createCallbackFcn(app, @ModC2_HelpMenuSelected, true);
            app.ModC2_Help.Text = 'Help';
            % Assign app.ModC2
            app.Current2EditFieldLabel.ContextMenu = app.ModC2;
            app.ModS2 = uicontextmenu(app.UIFigure);
            app.ModS2_Help = uimenu(app.ModS2);
            app.ModS2_Help.MenuSelectedFcn = createCallbackFcn(app, @ModS2_HelpMenuSelected, true);
            app.ModS2_Help.Text = 'Help';
            % Assign app.ModS2
            app.Slope2EditFieldLabel.ContextMenu = app.ModS2;
            app.ModAddMax2 = uicontextmenu(app.UIFigure);
            app.ModAddMax2_Help = uimenu(app.ModAddMax2);
            app.ModAddMax2_Help.MenuSelectedFcn = createCallbackFcn(app, @ModAddMax2_HelpMenuSelected, true);
            app.ModAddMax2_Help.Text = 'Help';
            % Assign app.ModAddMax2
            app.MaxKmEditFieldLabel_2.ContextMenu = app.ModAddMax2;
            app.ModCalc = uicontextmenu(app.UIFigure);
            app.ModCalc_Help = uimenu(app.ModCalc);
            app.ModCalc_Help.MenuSelectedFcn = createCallbackFcn(app, @ModCalc_HelpMenuSelected, true);
            app.ModCalc_Help.Text = 'Help';
            % Assign app.ModCalc
            app.CalculateKm.ContextMenu = app.ModCalc;
            app.ModPhase = uicontextmenu(app.UIFigure);
            app.ModPhase_Help = uimenu(app.ModPhase);
            app.ModPhase_Help.MenuSelectedFcn = createCallbackFcn(app, @ModPhase_HelpMenuSelected, true);
            app.ModPhase_Help.Text = 'Help';
            % Assign app.ModPhase
            app.AddPhase.ContextMenu = app.ModPhase;
            app.ModExport = uicontextmenu(app.UIFigure);
            app.ModExport_Help = uimenu(app.ModExport);
            app.ModExport_Help.MenuSelectedFcn = createCallbackFcn(app, @ModExport_HelpMenuSelected, true);
            app.ModExport_Help.Text = 'Help';
            % Assign app.ModExport
            app.ExportAddPhase.ContextMenu = app.ModExport;
            app.ModUpdate = uicontextmenu(app.UIFigure);
            app.ModUpdate_Help = uimenu(app.ModUpdate);
            app.ModUpdate_Help.MenuSelectedFcn = createCallbackFcn(app, @ModUpdate_HelpMenuSelected, true);
            app.ModUpdate_Help.Text = 'Help';
            % Assign app.ModUpdate
            app.UpdateTables.ContextMenu = app.ModUpdate;
            app.CompSelect = uicontextmenu(app.UIFigure);
            app.CompSelect_Help = uimenu(app.CompSelect);
            app.CompSelect_Help.MenuSelectedFcn = createCallbackFcn(app, @CompSelect_HelpMenuSelected, true);
            app.CompSelect_Help.Text = 'Help';
            % Assign app.CompSelect
            app.SelectPhaseDropDownLabel.ContextMenu = app.CompSelect;
            app.Comp = uicontextmenu(app.UIFigure);
            app.Comp_Help = uimenu(app.Comp);
            app.Comp_Help.MenuSelectedFcn = createCallbackFcn(app, @Comp_HelpMenuSelected, true);
            app.Comp_Help.Text = 'Help';
            % Assign app.Comp
            app.AddCompositePhaseTab.ContextMenu = app.Comp;
            app.CompAdd = uicontextmenu(app.UIFigure);
            app.CompAdd_Help = uimenu(app.CompAdd);
            app.CompAdd_Help.MenuSelectedFcn = createCallbackFcn(app, @CompAdd_HelpMenuSelected, true);
            app.CompAdd_Help.Text = 'Help';
            % Assign app.CompAdd
            app.AddButton.ContextMenu = app.CompAdd;
            app.CompTable = uicontextmenu(app.UIFigure);
            app.CompTable_Help = uimenu(app.CompTable);
            app.CompTable_Help.MenuSelectedFcn = createCallbackFcn(app, @CompTable_HelpMenuSelected, true);
            app.CompTable_Help.Text = 'Help';
            % Assign app.CompTable
            app.AddCompositeDatabase.ContextMenu = app.CompTable;
            app.CompCombine = uicontextmenu(app.UIFigure);
            app.CompCombine_Help = uimenu(app.CompCombine);
            app.CompCombine_Help.MenuSelectedFcn = createCallbackFcn(app, @CompCombine_HelpMenuSelected, true);
            app.CompCombine_Help.Text = 'Help';
            % Assign app.CompCombine
            app.CreateComposite.ContextMenu = app.CompCombine;
            app.CompUpdate = uicontextmenu(app.UIFigure);
            app.CompUpdate_Help = uimenu(app.CompUpdate);
            app.CompUpdate_Help.MenuSelectedFcn = createCallbackFcn(app, @CompUpdate_HelpMenuSelected, true);
            app.CompUpdate_Help.Text = 'Help';
            % Assign app.CompUpdate
            app.UpdateTables2.ContextMenu = app.CompUpdate;
            app.CompExport = uicontextmenu(app.UIFigure);
            app.CompExport_Help = uimenu(app.CompExport);
            app.CompExport_Help.MenuSelectedFcn = createCallbackFcn(app, @CompExport_HelpMenuSelected, true);
            app.CompExport_Help.Text = 'Help';
            % Assign app.CompExport
            app.CompositeExport.ContextMenu = app.CompExport;
            app.CreateRemove = uicontextmenu(app.UIFigure);
            app.CreateRemove_Help = uimenu(app.CreateRemove);
            app.CreateRemove_Help.MenuSelectedFcn = createCallbackFcn(app, @CreateRemove_HelpMenuSelected, true);
            app.CreateRemove_Help.Text = 'Help';
            % Assign app.CreateRemove
            app.RemoveDatapointButton.ContextMenu = app.CreateRemove;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Separation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end